classdef ChannelSelectionGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EasyNIRSChannelSelectionUIFigure  matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        SDFileButton            matlab.ui.control.Button
        DrawRegion1Button       matlab.ui.control.Button
        DrawRegion2Button       matlab.ui.control.Button
        FinishButton            matlab.ui.control.Button
        CenterPanel             matlab.ui.container.Panel
        Image                   matlab.ui.control.Image
        RightPanel              matlab.ui.container.Panel
        RegionComparisonLabel   matlab.ui.control.Label
        R1ChannelsListBoxLabel  matlab.ui.control.Label
        R1ChannelsListBox       matlab.ui.control.ListBox
        R2ChannelsListBoxLabel  matlab.ui.control.Label
        R2ChannelsListBox       matlab.ui.control.ListBox
        RegionOverviewLabel     matlab.ui.control.Label
        R1andR1CheckBox         matlab.ui.control.CheckBox
        R1andR2CheckBox         matlab.ui.control.CheckBox
        R2andR2CheckBox         matlab.ui.control.CheckBox
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        ChAxes   % Description
        Data % Description
        ParentHandle %Description
    end
    
    properties (Access = private)
        ChannelCoords % Description
        SD_Data % Description
        CHUI % Description
        backgroundAxes % Description
        
    end
    
    methods (Access = private)
        
        function [SD_Data] = openSDFile(app)
         
            %%get file 
            [filename, pathname] = uigetfile(); %{'*.SD'; '*.sd';'*.nirs'; '*.mat'}
            %%handle cancel event
            if(filename == 0)
                return;
            end
            
            [filedata, err] = sdFileLoad(app,[pathname filename]);
            if err
                return;
            end
            
            %%HMR 2 func, initialize SD object
            sd_data_Init(filedata.SD)
            
            err = sd_data_ErrorFix();
            if err
                return
            end
            
            SD_Data.SrcPos   = sd_data_Get('SrcPos');
            SD_Data.SrcMap   = sd_data_Get('SrcMap');
            SD_Data.DetPos   = sd_data_Get('DetPos');
            SD_Data.DummyPos = sd_data_Get('DummyPos');
            SD_Data.ml       = sd_data_GetMeasList();
            SD_Data.sl       = sd_data_GetSpringList();
            SD_Data.al       = sd_data_GetAnchorList();
            SD_Data.Lambda   = sd_data_Get('Lambda');
            SD_Data.SpatialUnit   = sd_data_Get('SpatialUnit');
            
            app.SD_Data = SD_Data;
            
            disp('opened sd file successfully')
            
            
        end
        
        function [filedata, err] = sdFileLoad(~, filename)
            %%initialize vars
            err = 0;
            [~, fname, ext] = fileparts(filename);
            
            if exist(filename,'file')==7
                err=1;
                %display error message
                %SDgui_disp_msg(handles, sprintf('ERROR: %s is a folder. Please choose a file.', [fname,ext]), err);
                return;
            end
            
            if isempty(filename) || exist(filename,'file')==0
                err=2;
                %display error message
                %SDgui_disp_msg(handles, sprintf('ERROR: file %s does not exist.', [fname,ext]), err);
                return;
            end
            
            try
                filedata = load(filename,'-mat');
            catch
                err=3;
                SDgui_disp_msg(handles, sprintf('ERROR: File %s is not in .mat format.', [fname,ext]), err);
                return;
            end
            
            if ~isfield(filedata,'SD')
                err=4;
                SDgui_disp_msg(handles, sprintf('ERROR: SD data doesn''t exist or is corrupt in %s.', [fname,ext]), err);
                return;
            end

        
        end
        
        function [] = plotSourceDetectors(app, SourceLocations, DetectorLocations, map)
        
            sourceX = SourceLocations(:,1,1);
            detectorX = DetectorLocations(:,1,1);
            
            xmin = min(min(sourceX), min(detectorX));
            xmax = max(max(sourceX), max(detectorX));
            
            sourceY = SourceLocations(:,2,1);
            detectorY = DetectorLocations(:,2,1);
            
            ymin = min(min(sourceY),min(detectorY));
            ymax = max(max(detectorX),max(detectorY));
            
            app.ChAxes.XLim = [xmin-4, xmax+4];
            app.ChAxes.YLim = [ymin-4, ymax+4];
            
            %%plot map
            hold(app.ChAxes,'on')
            channelHandles = [];
            for i = 1:length(map)
                
                sourceIdx = map(i,1);
                detectorIdx = map(i, 2);
                
                X = [sourceX(sourceIdx), detectorX(detectorIdx)];
                Y = [sourceY(sourceIdx), detectorY(detectorIdx)];
                i = plot(app.ChAxes, X, Y, 'Color',[1,1,1], 'LineWidth',2);
                channelHandles = [channelHandles, i];
            end
            
            app.ChannelCoords.channelHandles = channelHandles;
            disp('plot source detectors')
            
            
            sdX = vertcat(sourceX, detectorX);
            sdY = vertcat(sourceY, detectorY);
            
            app.ChannelCoords.sdX = sdX;
            app.ChannelCoords.sdY = sdY;
            app.ChannelCoords.numSources = length(sourceY);
            
            dim = size(sdX);
            colors = zeros(dim(1), 3);
            
            %set source color 
            colors(1:length(sourceX), 1) = 1;

            %set detector color
            colors(length(sourceX) + 1:end, 3) = 1;
       
            %plot source and detectors
            scatter(app.ChAxes,sdX,sdY, [],colors, 'filled')
        
            hold(app.ChAxes,'off')


        end
       
        function [] = poly1PosMoved(app, h)
        
           xVertices = h.Position(:,1);
           yVertices = h.Position(:,2);
           
           inPoly = inpolygon(app.ChannelCoords.sdX, app.ChannelCoords.sdY, xVertices, yVertices);
         
           activeChannels = {};
           for sourceNum = 1:app.ChannelCoords.numSources
               if inPoly(sourceNum)
              
                 %find indeces of source number i in ml
                 detIndeces = find(app.SD_Data.ml(:,1) == sourceNum);
                 
                 %check if those detectors are contained in it
                 detectors = app.SD_Data.ml(detIndeces,2);
                 for detNum = 1:length(detectors)
                     if inPoly(app.ChannelCoords.numSources + detectors(detNum))
                         
                         activeChannels{end + 1} = num2str(detIndeces(detNum));
                         
                     end
                 end
                 
               else 
                 
               end
           
           end

           if isfield(app.Data, 'R1Channels')
               previouslyActiveChannels = setdiff(str2double(app.Data.R1Channels), str2double(activeChannels));
               if isfield(app.Data, 'R2Channels')
                   previouslyActiveChannels = setdiff(previouslyActiveChannels, str2double(app.Data.R2Channels));
               end
               
           else 
               previouslyActiveChannels = [];
           end
           
           app.R1ChannelsListBox.Enable = 1;
           app.R1ChannelsListBoxLabel.Enable = 1;
           app.R1ChannelsListBox.Items = activeChannels;
           app.CHUI.R1 = 1;
           app.Data.R1Channels = activeChannels;
           
           
           for ch = 1:length(activeChannels)
               chCell = activeChannels(ch);
               app.ChannelCoords.channelHandles(str2double(chCell{:})).Color = 'g';
           end
           
           for ch = 1:length(previouslyActiveChannels)
               channel = previouslyActiveChannels(ch);
               app.ChannelCoords.channelHandles(channel).Color = 'white';
           end
           
        end
        
        function [] = poly2PosMoved(app, h)
        
           xVertices = h.Position(:,1);
           yVertices = h.Position(:,2);
           
           inPoly = inpolygon(app.ChannelCoords.sdX, app.ChannelCoords.sdY, xVertices, yVertices);
         
           activeChannels = {};
           for sourceNum = 1:app.ChannelCoords.numSources
               if inPoly(sourceNum)
              
                 %find indeces of source number i in ml
                 detIndeces = find(app.SD_Data.ml(:,1) == sourceNum);
                 
                 %check if those detectors are contained in it
                 detectors = app.SD_Data.ml(detIndeces,2);
                 for detNum = 1:length(detectors)
                     if inPoly(app.ChannelCoords.numSources + detectors(detNum))
                         
                         activeChannels{end + 1} = num2str(detIndeces(detNum));
                         
                     end
                 end
                 
               else 
                 
               end
           
           end

           if isfield(app.Data, 'R2Channels')
               previouslyActiveChannels = setdiff(str2double(app.Data.R2Channels), str2double(activeChannels));
               if isfield(app.Data, 'R1Channels')
                   previouslyActiveChannels = setdiff(previouslyActiveChannels, str2double(app.Data.R1Channels));
               end
           else 
               previouslyActiveChannels = [];
           end
           
           app.R2ChannelsListBox.Enable = 1;
           app.R2ChannelsListBoxLabel.Enable = 1;
           app.R2ChannelsListBox.Items = activeChannels;
           app.CHUI.R2 = 1;
           app.Data.R2Channels = activeChannels;
           
           
           for ch = 1:length(activeChannels)
               chCell = activeChannels(ch);
               app.ChannelCoords.channelHandles(str2double(chCell{:})).Color = 'g';
           end
           
           for ch = 1:length(previouslyActiveChannels)
               channel = previouslyActiveChannels(ch);
               app.ChannelCoords.channelHandles(channel).Color = 'white';
           end
           
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SDFileButton
        function SDFileButtonPushed(app, event)
            app.SD_Data = openSDFile(app);
           
            %background image axes setup
            app.backgroundAxes = axes(app.CenterPanel,'units', 'normalized', 'position', [0 0 1 1]);
            im = imread('head1.png');
            imagesc(app.backgroundAxes, im);
            colormap(app.backgroundAxes, 'default')
            set(app.backgroundAxes, 'handlevisibility', 'off', 'visible', 'off')
            disableDefaultInteractivity(app.backgroundAxes)
            
            %channel axes setup
            app.ChAxes = axes(app.CenterPanel);
            app.ChAxes.XAxis.Color = 'none';
            app.ChAxes.YAxis.Color = 'none';
            set(app.ChAxes, 'color', 'none');
            disableDefaultInteractivity(app.ChAxes)
            
            plotSourceDetectors(app, app.SD_Data.SrcPos, app.SD_Data.DetPos, app.SD_Data.ml);

            %%initialize region UI struct
            app.CHUI.R1 = 0;
            app.CHUI.R2 = 0;
        end

        % Button pushed function: DrawRegion1Button
        function DrawRegion1ButtonPushed(app, event)

           h1 = drawpolygon(app.ChAxes);
           addlistener(h1,'ROIMoved', @(src,evnt)poly1PosMoved(app,h1))
           
           app.R1andR1CheckBox.Enable = 1;

           poly1PosMoved(app,h1);
           
        end

        % Button pushed function: DrawRegion2Button
        function DrawRegion2ButtonPushed(app, event)

           h2 = drawpolygon(app.ChAxes,"Color",'magenta');
           addlistener(h2,'ROIMoved', @(src,evnt)poly2PosMoved(app,h2))
       
           app.R2andR2CheckBox.Enable = 1;
           app.R1andR2CheckBox.Enable = 1;

           poly2PosMoved(app,h2);
           
        end

        % Value changed function: R1andR1CheckBox
        function R1andR1CheckBoxValueChanged(app, event)
            value = app.R1andR1CheckBox.Value;
            app.Data.R1Intra = value;
            
        end

        % Value changed function: R1andR2CheckBox
        function R1andR2CheckBoxValueChanged(app, event)
            value = app.R1andR2CheckBox.Value;
            app.Data.R1R2 = value;
            
        end

        % Value changed function: R2andR2CheckBox
        function R2andR2CheckBoxValueChanged(app, event)
            value = app.R2andR2CheckBox.Value;
            app.Data.R2Intra = value;
            
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            %%check that ui has all nessecary info and pass back to other
            %%gui else disp a warning 
            
            app.ParentHandle.Data.RegionalData = app.Data;
            
           
            %check at least one region is drawn
            if ~isfield(app.Data, 'R1Channels') && ~isfield(app.Data, 'R2Channels')
                    warndlg('Create at least one region of interest.')
                    return
            end
            
            %check at least one region comparison is drawn
            if isfield(app.Data,'R1Intra')
                
            elseif isfield(app.Data, 'R1R2')
            elseif isfield(app.Data, 'R2Intra')
            else
                warndlg('Select at least one region comparison options.')
                return 
            end
            
            %now check the regions individually and make sure there is
            %adequate channels for the selected comparison(s).
            
            if (isfield(app.Data, 'R1Intra') && app.Data.R1Intra == 1)
                %then we need an R1 region drawn that contains > 0 channels
                if isfield(app.Data, 'R1Channels') && isempty(app.Data.R1Channels)
                    warndlg('R1 should contain at least one channel for R1 intra- comparison.')
                    return 
                end
            end
            
            if (isfield(app.Data, 'R2Intra') && app.Data.R2Intra == 1)
                %then we need an R2 region drawn that contains > 0 channels
                if isfield(app.Data, 'R2Channels') && isempty(app.Data.R1Channels)
                    warndlg('R2 should contain at least one channel for R2 intra- comparison.')
                    return 
                end
            end
            
            if (isfield(app.Data, 'R1R2') && app.Data.R1R2 == 1)
                %then we need both an R1 and R2 region drawn that contains > 0 channels
      
                if (isfield(app.Data, 'R1Channels') && isempty(app.Data.R1Channels)) || (isfield(app.Data, 'R2Channels') && isempty(app.Data.R2Channels))
                    warndlg('Both regions should contain at least one channel for R1 and R2 comparison.')
                    return
                end
            end
           
            
            %fill channels in main gui
            %First clear the list boxes incase previous data isin't overwritten
            app.ParentHandle.Region1ListBox.Items = {};
            app.ParentHandle.Region2ListBox.Items = {};
            
            if isfield(app.Data, 'R1Channels')
                app.ParentHandle.Region1ListBox.Items = app.Data.R1Channels;
            end 
            if isfield(app.Data, 'R2Channels')
                app.ParentHandle.Region2ListBox.Items = app.Data.R2Channels;
            end 
            
            close(app.EasyNIRSChannelSelectionUIFigure)
            
        end

        % Value changed function: R1ChannelsListBox
        function R1ChannelsListBoxValueChanged(app, event)
            app.R1ChannelsListBox.Value = {};
        end

        % Value changed function: R2ChannelsListBox
        function R2ChannelsListBoxValueChanged(app, event)
            app.R2ChannelsListBox.Value = {};
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.EasyNIRSChannelSelectionUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {224, '1x', 230};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EasyNIRSChannelSelectionUIFigure = uifigure('Visible', 'off');
            app.EasyNIRSChannelSelectionUIFigure.Name = 'Easy NIRS Channel Selection';
            app.EasyNIRSChannelSelectionUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.EasyNIRSChannelSelectionUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SDFileButton = uibutton(app.LeftPanel, 'push');
            app.SDFileButton.ButtonPushedFcn = createCallbackFcn(app, @SDFileButtonPushed, true);
            app.SDFileButton.Text = 'SD File';
            app.DrawRegion1Button = uibutton(app.LeftPanel, 'push');
            app.DrawRegion1Button.ButtonPushedFcn = createCallbackFcn(app, @DrawRegion1ButtonPushed, true);
            app.DrawRegion1Button.Text = 'Draw Region 1';
            app.DrawRegion2Button = uibutton(app.LeftPanel, 'push');
            app.DrawRegion2Button.ButtonPushedFcn = createCallbackFcn(app, @DrawRegion2ButtonPushed, true);
            app.DrawRegion2Button.Text = 'Draw Region 2';
            app.FinishButton = uibutton(app.LeftPanel, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            app.CenterPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.CenterPanel);
            app.Image.Visible = 'off';
            app.Image.ImageSource = 'head.png';
            app.RightPanel = uipanel(app.GridLayout);
            app.RegionComparisonLabel = uilabel(app.RightPanel);
            app.RegionComparisonLabel.Text = 'Region Comparison';
            app.R1ChannelsListBoxLabel = uilabel(app.RightPanel);
            app.R1ChannelsListBoxLabel.Enable = 'off';
            app.R1ChannelsListBoxLabel.Text = 'R1 Channels';
            app.R1ChannelsListBox = uilistbox(app.RightPanel);
            app.R1ChannelsListBox.Items = {};
            app.R1ChannelsListBox.ValueChangedFcn = createCallbackFcn(app, @R1ChannelsListBoxValueChanged, true);
            app.R1ChannelsListBox.Enable = 'off';
            app.R1ChannelsListBox.Value = {};
            app.R2ChannelsListBoxLabel = uilabel(app.RightPanel);
            app.R2ChannelsListBoxLabel.Enable = 'off';
            app.R2ChannelsListBoxLabel.Text = 'R2 Channels';
            app.R2ChannelsListBox = uilistbox(app.RightPanel);
            app.R2ChannelsListBox.Items = {'', ''};
            app.R2ChannelsListBox.ValueChangedFcn = createCallbackFcn(app, @R2ChannelsListBoxValueChanged, true);
            app.R2ChannelsListBox.Enable = 'off';
            app.R2ChannelsListBox.Value = {};
            app.RegionOverviewLabel = uilabel(app.RightPanel);
            app.RegionOverviewLabel.Text = 'Region Overview';
            app.R1andR1CheckBox = uicheckbox(app.RightPanel);
            app.R1andR1CheckBox.ValueChangedFcn = createCallbackFcn(app, @R1andR1CheckBoxValueChanged, true);
            app.R1andR1CheckBox.Enable = 'off';
            app.R1andR1CheckBox.Text = 'R1 and R1';
            app.R1andR2CheckBox = uicheckbox(app.RightPanel);
            app.R1andR2CheckBox.ValueChangedFcn = createCallbackFcn(app, @R1andR2CheckBoxValueChanged, true);
            app.R1andR2CheckBox.Enable = 'off';
            app.R1andR2CheckBox.Text = 'R1 and R2';
            app.R2andR2CheckBox = uicheckbox(app.RightPanel);
            app.R2andR2CheckBox.ValueChangedFcn = createCallbackFcn(app, @R2andR2CheckBoxValueChanged, true);
            app.R2andR2CheckBox.Enable = 'off';
            app.R2andR2CheckBox.Text = 'R2 and R2';
            % Show the figure after all components are created
            app.EasyNIRSChannelSelectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ChannelSelectionGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EasyNIRSChannelSelectionUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EasyNIRSChannelSelectionUIFigure)
        end
    end
end