classdef TaskSelector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EasyNIRS              matlab.ui.Figure
        Panel                 matlab.ui.container.Panel
        SubjectLabel          matlab.ui.control.Label
        BuildPanel            matlab.ui.container.Panel
        MarkStartButton       matlab.ui.control.Button
        MarkEndButton         matlab.ui.control.Button
        MarkStimButton        matlab.ui.control.Button
        HoldshapeCheckBox     matlab.ui.control.CheckBox
        StartEditFieldLabel   matlab.ui.control.Label
        StartEditField        matlab.ui.control.NumericEditField
        EndEditFieldLabel     matlab.ui.control.Label
        EndEditField          matlab.ui.control.NumericEditField
        UndoMarkStimButton    matlab.ui.control.Button
        FilePanel             matlab.ui.container.Panel
        LoadTemplateButton    matlab.ui.control.Button
        SaveAsTemplateButton  matlab.ui.control.Button
        FinishSubjectButton   matlab.ui.control.Button
        OverviewPanel         matlab.ui.container.Panel
        StimsLabel            matlab.ui.control.Label
        StimsListBox          matlab.ui.control.ListBox
    end


    properties (Access = private)
        TimeAxes % Description
        PolyHandles % Description
        Data % Description
        TableView % Description
        TableData % Description
    end
    
    methods (Access = private)
        
        function [] = startPosMoved(app, h)
            app.StartEditField.Value = h.Position(1,1);
            
            if isfield(app.Data, 'HoldShape')
                if app.Data.HoldShape
                    %move all other poly's
                    app.PolyHandles.End.Position = [200,1;200,-1];
                    disp('updating the polies.')
                end
            end
            
        end
        
        function [] = endPosMoved(app, h)
            app.EndEditField.Value = h.Position(1,1);
        end
        
        function [] = stimMoved(app, h)

            % check if this function is being called due to a deleted one
            j = app.Data.deletedStimTag;
            if j ~= -1 
                j = str2num(j);
                app.TableData(j, :) = [];
           
                app.PolyHandles.StimRects(j) = [];
                app.TableView.Data = app.TableData;
                app.Data.deletedStimTag = -1;
                
                  
                if isempty(app.PolyHandles.StimRects)
                    app.UndoMarkStimButton.Enable = 0;
                end
                return 
            end
            
           if ~isempty(app.PolyHandles.StimRects)
               app.UndoMarkStimButton.Enable = 1;
           end

            idx = 0;
            %otherwise find out which stim was passed here
            for i = 1:length(app.PolyHandles.StimRects)
                 if app.PolyHandles.StimRects{i}.Tag == h.Tag %it was moved 
                     idx = i;
                     break;
                 end
            end
            
            %%update the stim label box

            name = ['stim ' num2str(h.Tag)];
            app.TableData{idx, 1} = name; %name
            app.TableData{idx, 2} = round(h.Position(1),2); %start 
            app.TableData{idx, 3} = round(h.Position(1) + h.Position(3), 2); %end
            
            app.TableView.Data = app.TableData;
             
            %%snap lb
            lowerBound = h.Position(1);
            realTime = find(abs((app.Data.StimTimes - lowerBound)) < 20);
            if ~isempty(realTime)
                min = 20;
                timeIdx = 1;
                for i = 1:length(realTime)
                    t = app.Data.StimTimes(realTime(i));
                    if abs(lowerBound - t) < min
                        min = abs(lowerBound - t);
                        timeIdx = i;
                    end
                end
                h.Position(1) = app.Data.StimTimes(realTime(timeIdx));
            end
            
            %%snap up
            upperBound = h.Position(1) + h.Position(3);
            realTime = find(abs((app.Data.StimTimes - upperBound)) < 20);
            if ~isempty(realTime)
                min = 20;
                timeIdx = 1;
                for i = 1:length(realTime)
                    t = app.Data.StimTimes(realTime(i));
                    if abs(lowerBound - t) < min
                        min = abs(lowerBound - t);
                        timeIdx = i;
                    end
                end
                h.Position(3) = app.Data.StimTimes(realTime(timeIdx)) - h.Position(1);
            end


        end
        
        function [] = initAxes(app, t, stimMarkers)
        
         
            app.Data.deletedStimTag = -1; %for keeping track of deleted stims, just setting initial value here
            
            
            app.TableView = uitable(app.OverviewPanel, 'Position', [20,27,223,169], 'RowName', {}, 'CellEditCallback', @updateRects);
            function updateRects (h,callbackdata)
                 app.TableData = h.Data;
                 updateStimRects(app, callbackdata)
            end
            
            app.TableView.ColumnName = {'Name','Start Time','End Time'};
            app.TableView.ColumnEditable = true;
            app.TableData = {};
            
            app.TimeAxes = axes(app.Panel, 'position', [0,0.10,1.0,0.8]);
            set(app.TimeAxes, 'color', 'white');
            disableDefaultInteractivity(app.TimeAxes)
            
            app.TimeAxes.XLim = [t(1), t(end)];
            app.TimeAxes.YLim = [-1, 1];
            
            app.StartEditField.Limits = app.TimeAxes.XLim;
            app.EndEditField.Limits = app.TimeAxes.XLim;
            
            xticklabels(app.TimeAxes, 'auto');
            app.TimeAxes.YAxis.Visible = 'off';
            app.TimeAxes.XColor = [1,1,1];
       
            
            stimHandles = [];
            stimTimes = [];
            
            for i = 1:length(stimMarkers)
                disp('marking stim')
                markerX = stimMarkers(i);
                timeX = t(markerX);
                l = line(app.TimeAxes, [timeX, timeX], [-1,1], 'LineWidth', 2);
                stimHandles(i) = l;
                stimTimes = [stimTimes, timeX];
            end
            
            app.Data.StimTimes = stimTimes;
            app.PolyHandles.Stim = stimHandles;
            disp('got file');
            
        end
        
        function [] = maskMoved(app,h)
            
            app.PolyHandles.Start.Position(:,1) = h.Position(1);
            startPosMoved(app, app.PolyHandles.Start);
            
            app.PolyHandles.End.Position(:,1) = h.Position(3) + h.Position(1);
            endPosMoved(app, app.PolyHandles.End);
            
            trans = (h.Position(1) - app.PolyHandles.MaskPosLB);
            app.PolyHandles.MaskPosLB = h.Position(1);
            for i = 1:length(app.PolyHandles.StimRects)
                app.PolyHandles.StimRects{i}.Position(1) = app.PolyHandles.StimRects{i}.Position(1) + trans;
                stimMoved(app, app.PolyHandles.StimRects{i});
            end
            
            start = round(h.Position(1),2);
            app.StartEditField = start;
            app.EndEditField.Value = num2str(round(h.Position(3), 2)) + start;
            
        end
        
        function [] = updateStimRects(app, callbackdata)
        
            disp('123')
            newValue = callbackdata.NewData;
            valueEdited = callbackdata.Indices(2);
            polyTag = callbackdata.Indices(1);
            
            rect = app.PolyHandles.StimRects{polyTag};
            if valueEdited == 2 %%start edited
                
                if newValue >= rect.Position(1) + rect.Position(3)
                    
                    v = newValue + rect.Position(3);
                    
                    if v > max(app.Data.t)
                        v = max(app.Data.t);
                    end
                    
                    app.TableData{polyTag, 3} = v;
                    app.TableView.Data = app.TableData;
                else 
                    rect.Position(3) = rect.Position(1) + rect.Position(3) - newValue;
                end
                
                rect.Position(1) = newValue;
               
            elseif valueEdited == 3 %%end edited
                
                if newValue <= rect.Position(1) 
                    rect.Position(1) = newValue - rect.Position(3);
                    
                    if rect.Position(1) < min(app.Data.t) 
                        rect.Position(1) = min(app.Data.t);
                    end
                    
                    app.TableData{polyTag, 2} = rect.Position(1);
                    app.TableView.Data = app.TableData;
                end
                
                rect.Position(3) = newValue - rect.Position(1);
                
            end
            
            app.PolyHandles.StimRects{polyTag} = rect;
            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainHandle, sub, stim, t)
            app.Data.mainHandle = mainHandle;
            app.Data.sub = sub;
            app.SubjectLabel.Text = sub;
            
            app.Data.t = t;
            stimMarkers = find(stim);
            
            if isempty(stimMarkers)
                stimMarkers = [1, length(stim)];
            elseif length(stimMarkers) < 2
                warndlg('Only found one stim marker in time series, using it at starting point and extending stim to end of time series.')
                stimMarkers = [1, length(stim)];
            end
            
            app.Data.StimIndeces = stimMarkers;
            initAxes(app, t, stimMarkers);
        end

        % Callback function
        function SelectDataButtonPushed(app, event)
        
%             %channel axes setup
%             fileName = uigetfile();
%             file = load(fileName);
%              
%             app.Data.t = file.Proc.t;
%             stimMarkers = find(file.Proc.s);
%             app.Data.StimIndeces = stimMarkers;
%             initAxes(app, file.Proc.t, stimMarkers);
            
        end

        % Button pushed function: MarkStartButton
        function MarkStartButtonPushed(app, event)
            
            mpos = min(app.Data.StimTimes);
            h = drawpolyline(app.TimeAxes,'Color', 'magenta', 'Position', [mpos,-1;mpos,1], 'InteractionsAllowed', "translate");
            
            app.MarkStartButton.Enable = 0;
            
            addlistener(h,'ROIMoved', @(src,evnt)startPosMoved(app,h))
            
            startPosMoved(app,h)
            
            app.PolyHandles.Start = h;
            
            app.StartEditField.Editable = 1 ;
            
            if isfield(app.PolyHandles, 'End')
                if isfield(app.PolyHandles, 'StimRects')
                    app.SaveAsTemplateButton.Enable = 1;
                    app.FinishSubjectButton.Enable = 1;
                end
            end
           
        end

        % Button pushed function: MarkEndButton
        function MarkEndButtonPushed(app, event)
            
            mpos = max(app.Data.StimTimes);
            h = drawpolyline(app.TimeAxes,'Color', 'blue', 'Position', [mpos,-1;mpos,1], 'InteractionsAllowed', "translate");
            
            app.MarkEndButton.Enable = 0;

            addlistener(h,'ROIMoved', @(src,evnt)endPosMoved(app,h))
            
            endPosMoved(app,h)
            
            app.PolyHandles.End = h;
            
            app.EndEditField.Editable = 1 ;
            
            if isfield(app.PolyHandles, 'Start')
                if isfield(app.PolyHandles, 'StimRects')
                    app.SaveAsTemplateButton.Enable = 1;
                    app.FinishSubjectButton.Enable = 1;
                end
            end
            
        end

        % Button pushed function: MarkStimButton
        function MarkStimButtonPushed(app, event)
            h1 = drawrectangle(app.TimeAxes,'Color', 'g', 'Position', [100,-1, 100,2], 'LineWidth', 2);
            
            disp(h1.Position)
            
            if ~isfield(app.PolyHandles, 'StimRects')
                app.PolyHandles.StimRects = {};
             
            end
            
            app.PolyHandles.StimRects{end + 1} = h1;
            h1.Tag = num2str(length(app.PolyHandles.StimRects));
            
            addlistener(h1,'ROIMoved', @(src,evnt)stimMoved(app,h1))
            
            stimMoved(app, h1)
            
            if isfield(app.PolyHandles, 'Start')
                if isfield(app.PolyHandles, 'End')
                    app.SaveAsTemplateButton.Enable = 1;
                    app.FinishSubjectButton.Enable = 1;
                end
            end
            
        end

        % Size changed function: Panel
        function PanelSizeChanged(app, event)
            position = app.Panel.Position; 
        end

        % Button pushed function: LoadTemplateButton
        function LoadTemplateButtonPushed(app, event)
            fileName = uigetfile('*.mat');
            
            if isequal(fileName, 0)
                return
            end
            
            file = load(fileName);
            disp('handle errors or cancels here')
            S = file.S;
            
            initAxes(app, S.t, S.StimIndeces)
            
            app.Data.StimNames = S.StimNames;
            
            app.SaveAsTemplateButton.Enable = 1;
            app.FinishSubjectButton.Enable = 1;
            
            app.MarkStartButton.Enable = 0;
            app.StartEditField.Editable = 1;
            app.MarkEndButton.Enable = 0;
            app.EndEditField.Editable = 1;
            
            %%markers 
            for i = 1:length(S.markerPos.StimRects)
                
                stimRect = S.markerPos.StimRects{i};
                h1 = drawrectangle(app.TimeAxes,'Color', 'g', 'Position', [stimRect(1,1),-1, stimRect(1,3),2]);

                if ~isfield(app.PolyHandles, 'StimRects')
                    app.PolyHandles.StimRects = {};
                end
                
                app.PolyHandles.StimRects{end + 1} = h1;
                h1.Tag = num2str(length(app.PolyHandles.StimRects));
                addlistener(h1,'ROIMoved', @(src,evnt)stimMoved(app,h1))
                stimMoved(app,h1)
                
            end


            %%create stim:
            %% start
            mpos = S.stimTimes(1);
            h = drawpolyline(app.TimeAxes,'Color', 'magenta', 'Position', [mpos,-1;mpos,1], 'InteractionsAllowed', "translate");

            addlistener(h,'ROIMoved', @(src,evnt)startPosMoved(app,h))
            startPosMoved(app,h)
            app.PolyHandles.Start = h;
            
            %% end
            mpos = S.stimTimes(end);
            h = drawpolyline(app.TimeAxes,'Color', 'blue', 'Position', [mpos,-1;mpos,1], 'InteractionsAllowed', "translate");

            addlistener(h,'ROIMoved', @(src,evnt)endPosMoved(app,h))
            endPosMoved(app,h)
            app.PolyHandles.End = h;


        end

        % Value changed function: StimsListBox
        function StimsListBoxValueChanged(app, event)
            app.StimsListBox.Value = {};          
        end

        % Value changed function: HoldshapeCheckBox
        function HoldshapeCheckBoxValueChanged(app, event)
             app.Data.HoldShape = app.HoldshapeCheckBox.Value;
            
             %% IF ON: 
             % create the overlying rect
             h = drawrectangle(app.TimeAxes,'Color', [0.4 0.4 0.4], 'Position', [app.PolyHandles.Start.Position(1),-1, abs(app.PolyHandles.End.Position(1) - app.PolyHandles.Start.Position(1)),2], 'InteractionsAllowed', "translate");
             app.PolyHandles.Mask = h;
             h.Tag = num2str(length(app.PolyHandles.StimRects));
             addlistener(h,'ROIMoved', @(src,evnt)maskMoved(app,h))
             app.PolyHandles.MaskPosLB = h.Position(1);
             
             % deactivate the current polys
             set(app.PolyHandles.Start, 'InteractionsAllowed', "none");
             set(app.PolyHandles.End, 'InteractionsAllowed', "none");
             for i = 1:length(app.PolyHandles.StimRects)
                 set(app.PolyHandles.StimRects{i}, 'InteractionsAllowed', "none");
             end
             
             for i = 1:length(app.PolyHandles.Stim)
                 set(app.PolyHandles.Stim(i), 'Color', 'none')
             end

             %setup start and duration labels.
             start = round(h.Position(1),2);
             app.StartEditField.Value = start;
             app.EndEditField.Value = num2str(round(h.Position(3), 2)) + start;
             
        end

        % Value changed function: EndEditField
        function EndEditFieldValueChanged(app, event)
            value = app.EndEditField.Value;
            app.PolyHandles.End.Position(1,1) = value;
            app.PolyHandles.End.Position(2,1) = value;
           
        end

        % Value changed function: StartEditField
        function StartEditFieldValueChanged(app, event)
            value = app.StartEditField.Value;
            app.PolyHandles.Start.Position(1,1) = value;
            app.PolyHandles.Start.Position(2,1) = value;
        end

        % Button pushed function: SaveAsTemplateButton
        function SaveAsTemplateButtonPushed(app, event)
            S.stimTimes = app.Data.StimTimes;
            S.markerPos.Start = app.PolyHandles.Start.Position;
            S.markerPos.End = app.PolyHandles.End.Position;
            
            stimRects = {};
            for i = 1:length(app.PolyHandles.StimRects)
                pos = app.PolyHandles.StimRects{i}.Position;
                stimRects{i} = pos;
            end
            
            S.markerPos.StimRects = stimRects;
            S.StimIndeces = app.Data.StimIndeces;
            S.t = app.Data.t;
            
            stimNames = {};
            for st = 1:size(app.TableData, 1)
                stimNames{end + 1} = app.TableData{st,1};
            end
       
            S.StimNames = stimNames;
            
            startingFolder = app.Data.mainHandle.Data.Path.study_path;
            [filename, pathname] = uiputfile('*.mat', 'Save Template As');
            newfilename = fullfile(pathname, filename);
            save(newfilename, 'S')
            
        end

        % Button pushed function: UndoMarkStimButton
        function UndoMarkStimButtonPushed(app, event)
        %WANT TO MAKE SURE THEY DONT UNDO THE START OR END SHAPES!
            %get handle
            handle = app.PolyHandles.StimRects{end};
            %delete shape, this also removes the listener according to
            %documentation which is nice 
            app.Data.deletedStimTag = handle.Tag;
            delete(handle);
            stimMoved(app,handle);
            %if no more stims disable button
           
        end

        % Button pushed function: FinishSubjectButton
        function FinishSubjectButtonPushed(app, event)
            %gather info, save into file bro
            %%pos of stim markers from s file, pos of start n end, post of
            %%stims 
            
            Snew.stimTimes = app.Data.StimTimes;
            Snew.markerPos.Start = app.PolyHandles.Start.Position;
            Snew.markerPos.End = app.PolyHandles.End.Position;
            
            stimRects = {};
            for i = 1:length(app.PolyHandles.StimRects)
                pos = app.PolyHandles.StimRects{i}.Position;
                stimRects{i} = pos;
            end
            
            Snew.markerPos.StimRects = stimRects;
            Snew.StimIndeces = app.Data.StimIndeces;
            Snew.t = app.Data.t;
            
            stimNames = {};
            for st = 1:size(app.TableData, 1)
                stimNames{end + 1} = app.TableData{st,1};
            end
       
            Snew.StimNames = stimNames;
            
            rawFile = [app.Data.mainHandle.Data.Path.RawData filesep app.Data.sub '.nirs'];
            save(rawFile, 'Snew', '-append')
            app.Data.mainHandle.updateInitializationStimStatus()
            close(app.EasyNIRS)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EasyNIRS = uifigure('Visible', 'off');
            app.EasyNIRS.Name = 'Stim Builder';
            app.Panel = uipanel(app.EasyNIRS);
            app.Panel.SizeChangedFcn = createCallbackFcn(app, @PanelSizeChanged, true);
            app.SubjectLabel = uilabel(app.EasyNIRS);
            app.SubjectLabel.Text = 'Subject';
            app.BuildPanel = uipanel(app.EasyNIRS);
            app.BuildPanel.Title = '  Build';
            app.MarkStartButton = uibutton(app.BuildPanel, 'push');
            app.MarkStartButton.ButtonPushedFcn = createCallbackFcn(app, @MarkStartButtonPushed, true);
            app.MarkStartButton.Text = 'Mark Start';
            app.MarkEndButton = uibutton(app.BuildPanel, 'push');
            app.MarkEndButton.ButtonPushedFcn = createCallbackFcn(app, @MarkEndButtonPushed, true);
            app.MarkEndButton.Text = 'Mark End';
            app.MarkStimButton = uibutton(app.BuildPanel, 'push');
            app.MarkStimButton.ButtonPushedFcn = createCallbackFcn(app, @MarkStimButtonPushed, true);
            app.MarkStimButton.Text = 'Mark Stim';
            app.HoldshapeCheckBox = uicheckbox(app.BuildPanel);
            app.HoldshapeCheckBox.ValueChangedFcn = createCallbackFcn(app, @HoldshapeCheckBoxValueChanged, true);
            app.HoldshapeCheckBox.Enable = 'off';
            app.HoldshapeCheckBox.Visible = 'off';
            app.HoldshapeCheckBox.Text = 'Hold shape';
            app.StartEditFieldLabel = uilabel(app.BuildPanel);
            app.StartEditFieldLabel.Text = 'Start:';
            app.StartEditField = uieditfield(app.BuildPanel, 'numeric');
            app.StartEditField.ValueChangedFcn = createCallbackFcn(app, @StartEditFieldValueChanged, true);
            app.StartEditField.Editable = 'off';
            app.EndEditFieldLabel = uilabel(app.BuildPanel);
            app.EndEditFieldLabel.Text = 'End:';
            app.EndEditField = uieditfield(app.BuildPanel, 'numeric');
            app.EndEditField.ValueChangedFcn = createCallbackFcn(app, @EndEditFieldValueChanged, true);
            app.EndEditField.Editable = 'off';
            app.UndoMarkStimButton = uibutton(app.BuildPanel, 'push');
            app.UndoMarkStimButton.ButtonPushedFcn = createCallbackFcn(app, @UndoMarkStimButtonPushed, true);
            app.UndoMarkStimButton.Text = 'Undo Mark Stim';
            app.FilePanel = uipanel(app.EasyNIRS);
            app.FilePanel.Title = '  File';
            app.LoadTemplateButton = uibutton(app.FilePanel, 'push');
            app.LoadTemplateButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTemplateButtonPushed, true);
            app.LoadTemplateButton.Text = 'Load Template';
            app.SaveAsTemplateButton = uibutton(app.FilePanel, 'push');
            app.SaveAsTemplateButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAsTemplateButtonPushed, true);
            app.SaveAsTemplateButton.Enable = 'off';
            app.SaveAsTemplateButton.Text = 'Save As Template';
            app.FinishSubjectButton = uibutton(app.FilePanel, 'push');
            app.FinishSubjectButton.ButtonPushedFcn = createCallbackFcn(app, @FinishSubjectButtonPushed, true);
            app.FinishSubjectButton.Enable = 'off';
            app.FinishSubjectButton.Text = 'Finish Subject';
            app.OverviewPanel = uipanel(app.EasyNIRS);
            app.OverviewPanel.Title = '  Overview';
            app.StimsLabel = uilabel(app.OverviewPanel);
            app.StimsLabel.Text = 'Stims:';
            app.StimsListBox = uilistbox(app.OverviewPanel);
            app.StimsListBox.Items = {};
            app.StimsListBox.ValueChangedFcn = createCallbackFcn(app, @StimsListBoxValueChanged, true);
            app.StimsListBox.Value = {};
            % Show the figure after all components are created
            app.EasyNIRS.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TaskSelector(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EasyNIRS)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EasyNIRS)
        end
    end
end