classdef fNIRSPipeLine < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EasyNIRSUIFigure                matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        InitializationTab               matlab.ui.container.Tab
        SelectAllSubjectsCheckBox_2     matlab.ui.control.CheckBox
        SubjectSelectionListBox_2       matlab.ui.control.ListBox
        SelectSubjectsforInitializationLabel  matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        RawDataButton_2                 matlab.ui.control.Button
        DemographicsButton_2            matlab.ui.control.Button
        StudyOutputFolderButton_2       matlab.ui.control.Button
        NoneSelectedLabel_5             matlab.ui.control.Label
        NoneSelectedLabel_6             matlab.ui.control.Label
        NoneSelectedLabel_7             matlab.ui.control.Label
        TechEnButton_2                  matlab.ui.control.StateButton
        NIRxButton_2                    matlab.ui.control.StateButton
        SelectFoldersLabel_2            matlab.ui.control.Label
        SelectDataTypeLabel_2           matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        BuildStimsButton                matlab.ui.control.Button
        BuildStimsforSelectedSubjectsLabel  matlab.ui.control.Label
        InitializedSubjectsListBoxLabel  matlab.ui.control.Label
        InitializedSubjectsListBox      matlab.ui.control.ListBox
        PreprocessingTab                matlab.ui.container.Tab
        CheckDataQualityPanel           matlab.ui.container.Panel
        Label_7                         matlab.ui.control.Label
        CriteriatoPruneChannelsLabel    matlab.ui.control.Label
        NoiseLabel                      matlab.ui.control.Label
        SNRmax                          matlab.ui.control.NumericEditField
        dBminLabel                      matlab.ui.control.Label
        DBminBox                        matlab.ui.control.NumericEditField
        dBmaxLabel                      matlab.ui.control.Label
        DBmaxBox                        matlab.ui.control.NumericEditField
        SDDistancemmLabel               matlab.ui.control.Label
        SDminBox                        matlab.ui.control.NumericEditField
        minLabel                        matlab.ui.control.Label
        SDmaxBox                        matlab.ui.control.NumericEditField
        maxLabel                        matlab.ui.control.Label
        dBrangeBox                      matlab.ui.control.CheckBox
        AmplitudedBLabel                matlab.ui.control.Label
        sdRangebox                      matlab.ui.control.CheckBox
        SNRbox                          matlab.ui.control.CheckBox
        maxLabel_2                      matlab.ui.control.Label
        hbMatchLabel                    matlab.ui.control.Label
        hbMatchBox                      matlab.ui.control.CheckBox
        MotionArtifactCorrectionPanel   matlab.ui.container.Panel
        SplineInterpolationautomatedLabel  matlab.ui.control.Label
        Spline_checkbox_2               matlab.ui.control.CheckBox
        SplinewSovitzkyGolayCHECKBOX_9  matlab.ui.control.CheckBox
        SmoothingLabel                  matlab.ui.control.Label
        SDwindowsLabel                  matlab.ui.control.Label
        SDwindowField                   matlab.ui.control.NumericEditField
        Panel_3                         matlab.ui.container.Panel
        SubjectSelectionListBox_3       matlab.ui.control.ListBox
        RunPreprocessingButton          matlab.ui.control.StateButton
        SelectAllSubjectsCheckBox_3     matlab.ui.control.CheckBox
        DataVisualizationPanel          matlab.ui.container.Panel
        CardiacFig                      matlab.ui.control.CheckBox
        SigQualityFig                   matlab.ui.control.CheckBox
        CorrectedFig                    matlab.ui.control.CheckBox
        CardiacFrequencyLabel           matlab.ui.control.Label
        SignalQualityLabel              matlab.ui.control.Label
        CorrectedSignalLabel            matlab.ui.control.Label
        DataQualitySummary              matlab.ui.control.CheckBox
        DataQualitySummaryLabel         matlab.ui.control.Label
        DataProcessingPanel             matlab.ui.container.Panel
        DetrendBox                      matlab.ui.control.CheckBox
        DetrendLabel                    matlab.ui.control.Label
        Downsample                      matlab.ui.control.CheckBox
        NormalizeLabel                  matlab.ui.control.Label
        NormalizeBox                    matlab.ui.control.CheckBox
        DownsampleLabel                 matlab.ui.control.Label
        TargetsamplerateHzEditFieldLabel  matlab.ui.control.Label
        TargetsamplerateHzEditField     matlab.ui.control.NumericEditField
        PreprocessingConfigurationLabel  matlab.ui.control.Label
        RunPreprocessingLabel           matlab.ui.control.Label
        PostprocessingTab               matlab.ui.container.Tab
        FZBandofinterestPanel           matlab.ui.container.Panel
        LowerBoundLabel                 matlab.ui.control.Label
        UpperBoundLabel                 matlab.ui.control.Label
        LB                              matlab.ui.control.NumericEditField
        UB                              matlab.ui.control.NumericEditField
        ChannelSelectionPanel           matlab.ui.container.Panel
        LaunchSDGuiButton               matlab.ui.control.StateButton
        Region1ListBox                  matlab.ui.control.ListBox
        Region2ListBox                  matlab.ui.control.ListBox
        EditField                       matlab.ui.control.EditField
        EditField_2                     matlab.ui.control.EditField
        HemoglobinParametersPanel_2     matlab.ui.container.Panel
        CheckallhemosignalsegHbOHbTHbLabel_2  matlab.ui.control.Label
        HbObox_2                        matlab.ui.control.CheckBox
        Hbbox_2                         matlab.ui.control.CheckBox
        OxyhemoglobinHbOLabel_2         matlab.ui.control.Label
        DeoxyhemoglobinHbLabel_2        matlab.ui.control.Label
        TotalhemoglobinTHbLabel_2       matlab.ui.control.Label
        OxygenSaturationStO2Label_2     matlab.ui.control.Label
        THbbox_2                        matlab.ui.control.CheckBox
        StO2box_2                       matlab.ui.control.CheckBox
        Panel_4                         matlab.ui.container.Panel
        SubjectSelectionListBox_4       matlab.ui.control.ListBox
        RunPostprocessingButton         matlab.ui.control.StateButton
        SelectAllSubjectsCheckBox_4     matlab.ui.control.CheckBox
        Label_8                         matlab.ui.control.Label
        PostprocessingConfigurationLabel  matlab.ui.control.Label
        RunPostprocessingLabel          matlab.ui.control.Label
        FigureSelectionPanel_3          matlab.ui.container.Panel
        CheckBox                        matlab.ui.control.CheckBox
        ChannelWiseStaticCoherencePlotLabel  matlab.ui.control.Label
        AnalysisTab                     matlab.ui.container.Tab
        FigureSelectionPanel_2          matlab.ui.container.Panel
        TimeVaryingWaveletTransformPowerLabel  matlab.ui.control.Label
        TimevaryingCoherenceLabel       matlab.ui.control.Label
        FrequencyVaryingCoherenceByTaskLabel  matlab.ui.control.Label
        tvwaveletfigure                 matlab.ui.control.CheckBox
        tvcohfigure                     matlab.ui.control.CheckBox
        fvcohfigure                     matlab.ui.control.CheckBox
        TimeVaryingPhaseLabel           matlab.ui.control.Label
        tvphasefigure                   matlab.ui.control.CheckBox
        AnalysisConfigurationLabel      matlab.ui.control.Label
        Panel_5                         matlab.ui.container.Panel
        RunAnalysisButton_2             matlab.ui.control.StateButton
        SubjectSelectionListBox_5       matlab.ui.control.ListBox
        SelectAllSubjectsCheckBox_5     matlab.ui.control.CheckBox
        SubjectswhohavebeenpostproccessedwillappearhereLabel  matlab.ui.control.Label
        RunAnalysisLabel                matlab.ui.control.Label
        RunAllTab                       matlab.ui.container.Tab
        RunAllLabel                     matlab.ui.control.Label
        SubjectSelectionListBox         matlab.ui.control.ListBox
        SelectAllSubjectsCheckBox_6     matlab.ui.control.CheckBox
        RunAllButton                    matlab.ui.control.Button
        SubjectswhohavebeeninitializedwillappearhereLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        postUI % Description 
        UI % Description
    end
    
    properties (Access = public)
        ChannelGUI % Description
        Data % Description
    end

    methods (Access = private)
    
        function [goodState] = testUIStateForPreprocessing(app)
        %%%
        %This function gathers information about what fields/boxes where selected
        %from the preprocessing screen and initializes the app.Data.ui
        %struct.
        %%%
            goodState = true;
            
            %% check data quality box 
        
            app.UI.Preprocessing.dbRangeBox = app.dBrangeBox.Value;
            app.UI.Preprocessing.sdRangebox = app.sdRangebox.Value;
            app.UI.Preprocessing.SNRbox = app.SNRbox.Value;
            app.UI.Preprocessing.hbMatchbox = app.hbMatchBox.Value;
            
            app.UI.Preprocessing.dBmin = app.DBminBox.Value;
            app.UI.Preprocessing.dBmax = app.DBmaxBox.Value;
            
            app.UI.Preprocessing.SDmin = app.SDminBox.Value;
            app.UI.Preprocessing.SDmax = app.SDmaxBox.Value;
            
            app.UI.Preprocessing.SNRmax = app.SNRmax.Value;


            if isequal([app.UI.Preprocessing.dbRangeBox, app.UI.Preprocessing.sdRangebox, app.UI.Preprocessing.SNRbox, ...
                    app.UI.Preprocessing.hbMatchbox], [0,0,0,0])
                warndlg('Please select at least one criterea to prune channels.')
                goodState = false;
                return 
            end
            
            %% Data Visualization box
            app.UI.Preprocessing.Figs.DataQuality = app.DataQualitySummary.Value;
            app.UI.Preprocessing.Figs.cardiacFreq = app.CardiacFig.Value;
            app.UI.Preprocessing.Figs.sigQuality = app.SigQualityFig.Value;
            app.UI.Preprocessing.Figs.corrSig = app.CorrectedFig.Value;
            
            %%Make sure a data type is Selected
            if ~(isfield(app.UI.Preprocessing, 'NIRx') || isfield(app.UI.Preprocessing, 'TechEn'))
                app.UI.Preprocessing.NIRx = 0;
                app.UI.Preprocessing.TechEn = 1;
            end
            
            %%Subjects 
            if isfield(app.Data, 'RunningAll')
                if ~app.Data.RunningAll
                    SOI = sort(app.SubjectSelectionListBox_3.Value);
            
                    %%Make sure at least one SOI is selected
                    if isempty(SOI)
                        warndlg('Please select at least one subject for processing.','Missing Data')
                        goodState = false;
                        return 
                    else
                        app.Data.SOI = SOI;
                    end
                    
                end
            else
                
                SOI = sort(app.SubjectSelectionListBox_3.Value);
            
                %%Make sure at least one SOI is selected
                if isempty(SOI)
                    warndlg('Please select at least one subject for processing.','Missing Data')
                    goodState = false;
                    return 
                else
                    app.Data.SOI = SOI;
                end
                
            end
            
            
            app.UI.Preprocessing.Spline = app.Spline_checkbox_2.Value;
            app.UI.Preprocessing.SDseconds = app.SDwindowField.Value;
            app.UI.Preprocessing.SGfilter = app.SplinewSovitzkyGolayCHECKBOX_9.Value;
            
            %%Data processing
            app.UI.Preprocessing.TFs = app.TargetsamplerateHzEditField.Value;
            app.UI.Preprocessing.Downsample = app.Downsample.Value;
            app.UI.Preprocessing.Detrend = app.DetrendBox.Value;
            app.UI.Preprocessing.Normalize = app.NormalizeBox.Value;
           
        end
       
        function [goodState] = testUIStateForPostprocessing(app)
        
            goodState = true;
            
            app.UI.Postprocessing.FZband = [app.LB.Value app.UB.Value];
            
            app.UI.Postprocessing.Fig = app.CheckBox.Value;
            
            Hemo = {};
        
            if app.HbObox_2.Value
                Hemo{end + 1} = 'HbO';
            end
            if app.Hbbox_2.Value
                Hemo{end + 1} = 'Hb'; 
            end
            if app.THbbox_2.Value
                Hemo{end + 1} = 'THb';
            end
            
            if app.StO2box_2.Value
                Hemo{end + 1} = 'StO2';
            end
            
            if isempty(Hemo)
                warndlg('Select at least one hemoglobin parameter for postprocessing.')
                goodState = false;
                return;
            end
            
            app.UI.Postprocessing.Hemo = Hemo;
            
            %%Subjects 
            if isfield(app.Data, 'RunningAll')
                if ~app.Data.RunningAll
                    SOI = sort(app.SubjectSelectionListBox_4.Value);
            
                    %%Make sure at least one SOI is selected
                    if isempty(SOI)
                        warndlg('Please select at least one subject for processing.','Missing Data')
                        goodState = false;
                        return 
                    else
                        app.Data.SOI = SOI;
                    end
                    
                end
            else
                
                SOI = sort(app.SubjectSelectionListBox_4.Value);
            
                %%Make sure at least one SOI is selected
                if isempty(SOI)
                    warndlg('Please select at least one subject for processing.','Missing Data')
                    goodState = false;
                    return 
                else
                    app.Data.SOI = SOI;
                end
                
            end


            %Check channels info
            if isfield(app.Data, 'RegionalData')
                
                app.Data.RegionalData.R1Name = app.EditField_2.Value;
                app.Data.RegionalData.R2Name = app.EditField.Value;
                
                if isfield(app.Data.RegionalData, 'R1Channels')
                   if ~isnumeric(app.Data.RegionalData.R1Channels)
                       r1Array = str2double(app.Data.RegionalData.R1Channels);
                       app.Data.RegionalData.R1Channels = r1Array;
                   end
                end
                if isfield(app.Data.RegionalData, 'R2Channels')
                   if ~isnumeric(app.Data.RegionalData.R2Channels)
                        r2Array = str2double(app.Data.RegionalData.R2Channels);
                        app.Data.RegionalData.R2Channels = r2Array;
                   end
                end
                if ~isfield(app.Data.RegionalData,'R1Intra')
                    app.Data.RegionalData.R1Intra = 0;
                end
                if ~isfield(app.Data.RegionalData,'R2Intra')
                    app.Data.RegionalData.R2Intra = 0;
                end
                if ~isfield(app.Data.RegionalData,'R1R2')
                    app.Data.RegionalData.R1R2 = 0;
                end   
            else
                warndlg('Select channels before postprocessing.')
                goodState = false;
                return;
            end
           
        end
        
        function [] = populateAnalysisSubjectList(app)
            % get initialized list
            initSubs = getInitializedSubjects(app);
            
            %get processed list
            preprocessedFolder = [app.Data.Path.study_path filesep 'Processed'];
            p = dir([preprocessedFolder filesep]);
            
            
            numberOfPre = length(p);
            
            ct = 1;
            preSubs = {};
            for i=1:length(p)
                
                if strcmp(p(i).name(1),'.')
                else
                    preSubs{ct}=p(i).name;
                    ct = ct+1;
                end
            end
            
            postSubs = {};
            ct = 1;
            for j = 1:length(preSubs)
                sub = preSubs(j);
                sub = sub{1};

                listing = dir([preprocessedFolder filesep sub]);
                
                for file = 1:length(listing)
                    nm = listing(file).name;
                    
                    if isequal(nm,'PostProcessed')
                        postSubs{ct} = sub;
                        ct = ct + 1;
                    end
                end
            end
            
            %take intersection
            common = intersect(postSubs, initSubs);
            app.SubjectSelectionListBox_5.Items = common;         
        
        end
        
        function [] = populatePostprocessingSubjectList(app)
            
    
            %get initialized list
            initSubs = getInitializedSubjects(app);
            
            %get preprocessed list
            preprocessedFolder = [app.Data.Path.study_path filesep 'Processed'];
            p = dir([preprocessedFolder filesep]);
            
            numberOfPre = length(p);
            
            ct = 1;
            preSubs = {};
            for i=1:length(p)
         
                if strcmp(p(i).name(1),'.')
                else
                    preSubs{ct}=p(i).name;
                    ct = ct+1;
                end
            end
            
            %take intersection
            common = intersect(preSubs, initSubs);
            app.SubjectSelectionListBox_4.Items = common;
            

        end
        
        function [] = populateRunAllSubjectList(app)
            initialized = getInitializedSubjects(app);
            app.SubjectSelectionListBox.Items = initialized;
        end
        
        function initSubs = getInitializedSubjects(app)
            rawDataFolder = [app.Data.Path.RawData];
            s = dir([rawDataFolder filesep '*.nirs']);
            numberOfInit = length(s);
            initSubs = {};
            for i = 1:numberOfInit
                varNames = who('-file', s(i).name);
                if ismember('Snew', varNames)
                    initSubs{end + 1} = s(i).name(1:end-5);
                end
            end
        end
        
        function goodState = testUIStateForAnalysis(app)
        
            goodState = true;
        
             %%Subjects 
            if isfield(app.Data, 'RunningAll')
                if ~app.Data.RunningAll
                    SOI = sort(app.SubjectSelectionListBox_5.Value);
            
                    %%Make sure at least one SOI is selected
                    if isempty(SOI)
                        warndlg('Please select at least one subject for processing.','Missing Data')
                        goodState = false;
                        return 
                    else
                        app.Data.SOI = SOI;
                    end
                    
                end
            else
                
                SOI = sort(app.SubjectSelectionListBox_5.Value);
            
                %%Make sure at least one SOI is selected
                if isempty(SOI)
                    warndlg('Please select at least one subject for processing.','Missing Data')
                    goodState = false;
                    return 
                else
                    app.Data.SOI = SOI;
                end
                
            end
            
            app.Data.postUI.analysisFigures = [app.tvcohfigure.Value, app.tvphasefigure.Value, app.tvwaveletfigure.Value, app.fvcohfigure.Value];
            
            
        end
    end
    
    methods (Access = public)
        
        function [] = stimInitCycle(app)
        
            app.InitializedSubjectsListBox.Items = getInitializedSubjects(app);
                   
           if app.Data.stimBuilderCount <= length(app.Data.SOI)
               disp(app.Data.stimBuilderCount);
               sub = (app.Data.SOI{app.Data.stimBuilderCount});
               subfile = [app.Data.Path.RawData filesep sub '.nirs'];
               X = load(subfile, '-mat');
               TaskSelector(app, sub, X.s(:,1), X.t);
               app.Data.stimBuilderCount = app.Data.stimBuilderCount + 1;
              
               disp(app.Data.stimBuilderCount);
           else
               populateRunAllSubjectList(app);
               app.BuildStimsButton.Enable = 1;
               app.BuildStimsButton.Text = "Build Stims";
               disp('done stims')
           end
                
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SelectAllSubjectsCheckBox_6
        function SelectAllSub_RunAll(app, event)
            if app.SelectAllSubjectsCheckBox_6.Value == 1
                app.SubjectSelectionListBox.Value = app.SubjectSelectionListBox.Items;
                app.Data.SOI = sort(app.SubjectSelectionListBox.Value);
            else 
                app.SubjectSelectionListBox.Value = {};
                app.Data.SOI = {};
            end
            
                
        end

        % Value changed function: NIRxButton_2
        function NIRxButtonValueChanged(app, event)
            value = app.TechEnButton_2.Value;
            app.UI.Preprocessing.NIRx = value;
            %%turn off techen
            app.TechEnButton_2.Value = ~value;
            app.UI.Preprocessing.TechEn = ~value;
            
            % based on NIRx software criteria
            app.DBminBox.Value = 110; app.UI.Preprocessing.dBmin = 110;
            app.DBmaxBox.Value = 148; app.UI.Preprocessing.dBmax = 148;
            app.SNRmax.Value = 7.5; app.UI.Preprocessing.SNRmax = 7.5;
            
      
        end

        % Value changed function: TechEnButton_2
        function TechEnButtonValueChanged(app, event)
        
            value = app.NIRxButton_2.Value;
            app.UI.Preprocessing.TechEn = app.TechEnButton_2.Value;
            %%turn off nirx
            
            app.NIRxButton_2.Value = ~value;
            app.UI.Preprocessing.NIRx = ~value;
            
            % based on Homer criteria
            app.DBminBox.Value = 80; app.UI.Preprocessing.dBmin = 80;
            app.DBmaxBox.Value = 125; app.UI.Preprocessing.dBMax = 125;
            app.SNRmax.Value = 5; app.UI.Preprocessing.SNRmax = 5;
        end

        % Button pushed function: StudyOutputFolderButton_2
        function StudyOutputButtonPushed(app, event)
            %get study path
            Folder = cd;
            disp(cd)
            addpath(genpath(Folder));
            Folder = fullfile(Folder, ['..' filesep]);
            app.Data.Path.study_path = uigetdir(Folder,'Select the study output folder');
            
            %handle cancel event
            if isequal(app.Data.Path.study_path, 0) 
               
            else
                %set study path and update label
                cd(app.Data.Path.study_path);
                addpath(genpath(app.Data.Path.study_path));
                app.NoneSelectedLabel_5.Text = app.Data.Path.study_path;
                
                %enable other button
                app.DemographicsButton_2.Enable = 1;
                app.RawDataButton_2.Enable = 1;
            end
        end

        % Button pushed function: DemographicsButton_2
        function DemographicsButtonPushed(app, event)
            %get demographics file and path
            
             %get study path
             disp('demographics button pushed')
%              Folder = cd;
%              addpath(genpath(Folder));
%              Folder = fullfile(Folder, '../');
             
             [Demographics, path] = uigetfile('.xlsx');
             
             %handle cancel even
             if isequal(Demographics, 0) 
                 disp('All subject will be assumed to be age 30 if not demographics file is supplied.')
             else
             app.Data.Demographics = readtable(Demographics);
     
             app.NoneSelectedLabel_6.Text = fullfile(path);
             end
             
        end

        % Button pushed function: RawDataButton_2
        function RawDataButtonPushed(app, event)
            %get raw data path if it exists
%             if isfield(app.Data,'Path')
%                 if isfield(app.Data.Path, 'study_path')
%                     app.Data.Path.RawData = uigetdir(app.Data.Path.study_path,'Select Raw Data folder');
%                 else
%                     app.Data.Path.RawData = uigetdir(app.Data.Path,'Select Raw Data folder');
%                 end
%                 
%             end
            Folder = cd;
            addpath(genpath(Folder));
            Folder = fullfile(Folder, ['..' filesep]);
            app.Data.Path.RawData = uigetdir(Folder,'Select the Raw Data folder');
            
            %handle cancel event
            if isequal(app.Data.Path.RawData, 0)
            %update label
            else
            app.NoneSelectedLabel_7.Text = app.Data.Path.RawData;
            end
            
            % Chris edit Jan 14 2019      
           listing = dir([app.Data.Path.RawData filesep '*.nirs']); % search RawData path for .nirs files to populate subject list
           nof=length(listing);
           for i=1:numel(listing)
               listing(i).name = listing(i).name(1:end-5); %length('.nirs') = 5
               nameoffile{i}=listing(i).name;
           end
           
           app.BuildStimsButton.Enable = 1;

           %%populate subject table
           app.SubjectSelectionListBox_3.Items = nameoffile; 
           app.SubjectSelectionListBox_2.Items = nameoffile;
           app.InitializedSubjectsListBox.Items = getInitializedSubjects(app);
           
           populatePostprocessingSubjectList(app);
           populateRunAllSubjectList(app);
           populateAnalysisSubjectList(app);
           
           
        end

        % Callback function
        function HighfzcutoffforbpfilterHzEditFieldValueChanged(app, event)

            app.Data.ui.hf = app.HighfzcutoffforbpfilterHzEditField.Value;
        end

        % Callback function
        function LowfzcutoffforbpfilterHzEditFieldValueChanged(app, event)
            app.Data.ui.lf = app.LowfzcutoffforbpfilterHzEditField.Value;   
        end

        % Value changed function: TargetsamplerateHzEditField
        function TargetsamplerateHzEditFieldValueChanged(app, event)
            app.UI.Preprocessing.TFs = app.TargetsamplerateHzEditField.Value;
        end

        % Value changed function: RunPreprocessingButton
        function RunPreprocessingButtonPushed(app, event)
            
            if testUIStateForPreprocessing(app)
                %preprocess data
                if app.UI.Preprocessing.NIRx
                    disp('converting nirx to nirs')
                    %NIRS IS TECHEN
                    %check if nirs for selected subjects exists
                    %if not get paths of NIRX files for selected subjects
                    %send paths to homerOffflineConverter
                    disp('Please select your probe.sb/.mat file');
                    [filename, pathname] = uigetfile();
                    if(filename == 0)
                        disp('Error: No probe file selected.')
                        return;
                    end
                    NIRx2nirs(app.Data.Path.RawData, [pathname filesep filename])
                  
                end
                app.RunPreprocessingButton.Text = "Running Preprocessing...";
                [app.Data] = Preprocessing(app.Data, app.UI.Preprocessing); 
                %save current data used to preprocess in PostData
                [PostData] = app.Data;
                app.RunPreprocessingButton.Text = "Run Preprocessing";
                app.postUI.preSubList = app.Data.SOI; %store current list for later reference
%                 clearvars -except PostData app
                msgbox('Preprocessing Completed'); 
                
                populatePostprocessingSubjectList(app);
                if isfield(app.Data, 'RunningAll')
                    if app.Data.RunningAll
                        app.Data.RanPreprocessing = 1;
                    end
                end
            else 
                if isfield(app.Data, 'RunningAll')
                    if app.Data.RunningAll
                        app.Data.RanPreprocessing = 0;
                    end
                end
                disp('ui was missing data for preprocessing')
                return
            end
            
        end

        % Callback function
        function TextAreaValueChanged(app, event)
%             %%converts excel format to array of numbers
%             sp = split(strip(app.TextArea.Value(1)), ','); %convert string to array of numbers by splitting at commas
%             %initialize empty list
%             app.Data.ui.AllSubs = [];
%            
%             %add each sp entry to list
%             for j = 1:length(sp)
%                 s = str2num(sp{j});
%                 for k = 1:length(s)
%                     app.Data.ui.AllSubs(end + 1) = s(k);
%                     
%                 end
%             
%             end
%             populateSubjectTable(app);   
        end

        % Value changed function: SubjectSelectionListBox, 
        % SubjectSelectionListBox_2, SubjectSelectionListBox_3, 
        % SubjectSelectionListBox_4, SubjectSelectionListBox_5
        function SubjectSelectionListBoxValueChanged(app, event)

            app.Data.SOI = sort(event.Value);    
        end

        % Value changed function: RunPostprocessingButton
        function RunPostprocessingButtonValueChanged(app, event)
            disp('Post processing buttton pushed nguy')
            
%             [Data1] = load('PostData.mat'); %ADD if to check if it exists before loading it
%             [PostData] = Data1.PostData;

            if testUIStateForPostprocessing(app)
                app.RunPostprocessingButton.Text = "Running Postprocessing...";
                %choose version of postprocessing
                %Uncomment line below to run V1 
                %Postprocessing_RS2018(PostData, app.Data.ui);
                %Uncomment line below to run V2   
                PostProcessing(app.Data, app.UI.Postprocessing);
                msgbox('PostProcessing Completed');
                app.RunPostprocessingButton.Text = "Run Postprocessing";
                populateAnalysisSubjectList(app);
                
                if isfield(app.Data, 'RunningAll')
                    if app.Data.RunningAll
                        app.Data.RanPostprocessing = 1;
                    end
                end
            else 
                if isfield(app.Data, 'RunningAll')
                    if app.Data.RunningAll
                        app.Data.RanPostprocessing = 0;
                    end
                end
                disp('data was missing from UI in order to post processssssßsšsšssśss')
            end
          
        end

        % Value changed function: LB
        function LBValueChanged(app, event)
            app.UI.Postprocessing.FreqLB = app.LB.Value;
            
        end

        % Value changed function: UB
        function UBValueChanged(app, event)
            app.UI.Postprocessing.FreqUB = app.UB.Value;
            
        end

        % Value changed function: SDwindowField
        function SDwindowFieldValueChanged(app, event)
            disp('SDSecondsChanged')
            app.UI.Preprocessing.SDseconds = app.SDwindowField.Value;
            
        end

        % Button pushed function: RunAllButton
        function RunAllButtonPushed(app, event)
        
            
            app.Data.RunningAll = 1;
            
            SOI = sort(app.SubjectSelectionListBox.Value);
            
            %%Make sure at least one SOI is selected
            if isempty(SOI)
                warndlg('Please select at least one subject for processing.','Missing Data')
                return 
            else
                app.Data.SOI = SOI;
            end
            
            %try to run preprocessing, if data is missing from UI this
            %function will end with app.Data.RanPreprocessing = 0, else if
            %preprocessing ran successfully it will end with
            %app.Data.Ranpreprocessing == 1
            RunPreprocessingButtonPushed(app, event);
            if app.Data.RanPreprocessing 
                %in which case we try to run postprocessing
                RunPostprocessingButtonValueChanged(app, event);
                if app.Data.RanPostprocessing
                    %likewise is data was missing from ui
                    %app.Data.RanPostprocessing == 0
                    RunAnalysisButtonValueChanged(app, event);
                end
            end
            
            %reset state
            app.Data.RanPreprocessing = 0; 
            app.Data.RanPostprocessing = 0;
            app.Data.RunningAll = 0;
        end

        % Value changed function: LaunchSDGuiButton
        function LaunchSDGuiButtonValueChanged(app, event)
            disp('LaunchSDGUIButtonValueChanged')
            app.ChannelGUI = ChannelSelectionGUI();
            app.ChannelGUI.ParentHandle = app;
        end

        % Callback function
        function SplinewSovitzkyGolayCHECKBOXValueChanged(app, event)
            app.UI.Preprocessing.SGfilter = app.SplinewSovitzkyGolayCHECKBOX.Value;
            
        end

        % Value changed function: SNRmax
        function SNRmaxValueChanged(app, event)
            app.UI.Preprocessing.SNRmax = app.SNRmax.Value;
            
        end

        % Value changed function: DBminBox
        function DBminBoxValueChanged(app, event)
            app.UI.Preprocessing.dBmin = app.DBminBox.Value;
        end

        % Value changed function: DBmaxBox
        function DBmaxBoxValueChanged(app, event)
            app.UI.Preprocessing.dBmax = app.DBminBox.Value;
        end

        % Value changed function: SDminBox
        function SDminBoxValueChanged(app, event)
            app.UI.Preprocessing.SDmin = app.SDminBox.Value;
        end

        % Value changed function: SDmaxBox
        function SDmaxBoxValueChanged(app, event)
            app.UI.Preprocessing.SDmax = app.SDmaxBox.Value;
        end

        % Value changed function: Spline_checkbox_2
        function Spline_checkboxValueChanged(app, event)
        
            value = app.Spline_checkbox_2.Value;
        
            app.UI.Preprocessing.Spline = value;
         
            
        end

        % Value changed function: dBrangeBox
        function dBrangeBoxValueChanged(app, event)
            disp('dbRangeBoxValue changed caleld')
            value = app.dBrangeBox.Value;
            app.UI.Preprocessing.dbRangeBox = value;
            
            if value == 1 %%ensure values are in there mang
                app.UI.Preprocessing.dBmin = app.DBminBox.Value;
                app.UI.Preprocessing.dBmax = app.DBmaxBox.Value;
            end
            
            %hide/ unhide the fields
            app.dBminLabel.Enable = value;
            app.DBminBox.Enable = value;
            app.dBmaxLabel.Enable = value;
            app.DBmaxBox.Enable = value;
            
            
        end

        % Value changed function: SNRbox
        function SNRboxValueChanged(app, event)
            value = app.SNRbox.Value;
            app.UI.Preprocessing.SNRbox = value;
            
            if value == 1
                app.UI.Preprocessing.SNRmax = app.SNRmax.Value;
            end
            
            %hide/ unhide field
            app.maxLabel_2.Enable = value;
            app.SNRmax.Enable = value;
        end

        % Value changed function: hbMatchBox
        function hbMatchBoxValueChanged(app, event)
            app.UI.Preprocessing.hbMatchbox = app.hbMatchBox.Value;
        end

        % Callback function
        function SelectPreprocessedDataButtonPushed(app, event)
%             % get preprocessed files path
%             %             if exist(app.Data.Path.study_path)
%             %                 cd(app.Data.Path.study_path)
%             %             end
%             Folder = cd;
%             app.Data.Path.pre_path = uigetdir(Folder,'Select "Processed" data folder');
%             %handle cancel event
%             if isequal(app.Data.Path.pre_path, 0)
%             else
%                 %set path and update label
%                 addpath(genpath(app.Data.Path.pre_path));
%                 cd(app.Data.Path.pre_path);
%                 app.NoneSelectedLabel_4.Text = app.Data.Path.pre_path;
%             end
%             
%             listing = dir([app.Data.Path.pre_path filesep]); % search processed path for .mat files to populate subject list
%             nof=length(listing); %need to fix that it pulls two extra folder names '.' and '..''
%             disp(nof)
%             disp(listing)
%             ct = 1;
%             for i=1:numel(listing)
%                 disp('ppp')
%                 if strcmp(listing(i).name,'.') || strcmp(listing(i).name,'..') || strcmp(listing(i).name, '.DS_Store')
%                 else
%                     nameoffile{ct}=listing(i).name;
%                     ct = ct+1;
%                 end
%             end
%             
%             app.Data.AllSubs = nameoffile;
%             app.SubjectSelectionListBox.Items = nameoffile;
 
        end

        % Value changed function: RunAnalysisButton_2
        function RunAnalysisButtonValueChanged(app, event)

            if testUIStateForAnalysis(app)
                PostAnalysis_CD(app.Data)
            else
                disp('data was missing for analysis')
            end
            
                
        end

        % Value changed function: SplinewSovitzkyGolayCHECKBOX_9
        function SplinewSovitzkyGolayCHECKBOX_9ValueChanged(app, event)
            value = app.SplinewSovitzkyGolayCHECKBOX_9.Value; 
        end

        % Callback function
        function selectall(app, event)
            value = app.SelectAllSubjectsCheckBox_2.Value;
        end

        % Button pushed function: BuildStimsButton
        function BuildStimsButtonPushed(app, event)
            
            if isempty(app.SubjectSelectionListBox_2.Value)
                warndlg('Select at least one subject for initialization')
                return
            end
            app.Data.SOI = app.SubjectSelectionListBox_2.Value;
            app.Data.stimBuilderCount = 1;
            app.BuildStimsButton.Enable = 0;
            app.BuildStimsButton.Text = "Building Stims";
            stimInitCycle(app);
               
        end

        % Value changed function: sdRangebox
        function sdRangeboxValueChanged(app, event)
            value = app.sdRangebox.Value;
            app.UI.Preprocessing.sdRangebox = value;
            
            if value == 1
                app.UI.Preprocessing.SDmin = app.SDminBox.Value;
                app.UI.Preprocessing.SDmax = app.SDmaxBox.Value;
            end
            
            %hide/ unhide the fields
            app.minLabel.Enable = value;
            app.SDminBox.Enable = value;
            app.maxLabel.Enable = value;
            app.SDmaxBox.Enable = value;
            
        end

        % Value changed function: Downsample
        function DownsampleValueChanged(app, event)
            app.TargetsamplerateHzEditFieldLabel.Enable = app.Downsample.Value;
            app.TargetsamplerateHzEditField.Enable = app.Downsample.Value;
            
        end

        % Value changed function: InitializedSubjectsListBox
        function InitializedSubjectsListBoxValueChanged(app, event)
            app.InitializedSubjectsListBox.Value = {};
            
        end

        % Value changed function: SelectAllSubjectsCheckBox_5
        function SelectAllSubjectsCheckBox_5ValueChanged(app, event)

            if app.SelectAllSubjectsCheckBox_5.Value == 1
                app.SubjectSelectionListBox_5.Value = app.SubjectSelectionListBox_5.Items;
                app.Data.SOI = sort(app.SubjectSelectionListBox_5.Value);
            else 
                app.SubjectSelectionListBox_5.Value = {};
                app.Data.SOI = {};
            end
        end

        % Value changed function: SelectAllSubjectsCheckBox_4
        function SelectAllSubjectsCheckBox_4ValueChanged(app, event)

            if app.SelectAllSubjectsCheckBox_4.Value == 1
                app.SubjectSelectionListBox_4.Value = app.SubjectSelectionListBox_4.Items;
                app.Data.SOI = sort(app.SubjectSelectionListBox_4.Value);
            else 
                app.SubjectSelectionListBox_4.Value = {};
                app.Data.SOI = {};
            end
        end

        % Value changed function: SelectAllSubjectsCheckBox_3
        function SelectAllSubjectsCheckBox_3ValueChanged(app, event)
            if app.SelectAllSubjectsCheckBox_3.Value == 1
                app.SubjectSelectionListBox_3.Value = app.SubjectSelectionListBox_3.Items;
                app.Data.SOI = sort(app.SubjectSelectionListBox_3.Value);
            else 
                app.SubjectSelectionListBox_3.Value = {};
                app.Data.SOI = {};
            end
            
        end

        % Value changed function: SelectAllSubjectsCheckBox_2
        function SelectAllSubjectsCheckBox_2ValueChanged(app, event)
            
            if app.SelectAllSubjectsCheckBox_2.Value == 1
                app.SubjectSelectionListBox_2.Value = app.SubjectSelectionListBox_2.Items;
                app.Data.SOI = sort(app.SubjectSelectionListBox_2.Value);
            else 
                app.SubjectSelectionListBox_2.Value = {};
                app.Data.SOI = {};
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EasyNIRSUIFigure = uifigure('Visible', 'off');
            app.EasyNIRSUIFigure.Name = 'Easy NIRS';
            app.TabGroup = uitabgroup(app.EasyNIRSUIFigure);
            app.InitializationTab = uitab(app.TabGroup);
            app.InitializationTab.Title = 'Initialization';
            app.SelectAllSubjectsCheckBox_2 = uicheckbox(app.InitializationTab);
            app.SelectAllSubjectsCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @SelectAllSubjectsCheckBox_2ValueChanged, true);
            app.SelectAllSubjectsCheckBox_2.Text = ' Select All Subjects';
            app.SubjectSelectionListBox_2 = uilistbox(app.InitializationTab);
            app.SubjectSelectionListBox_2.Items = {};
            app.SubjectSelectionListBox_2.Multiselect = 'on';
            app.SubjectSelectionListBox_2.ValueChangedFcn = createCallbackFcn(app, @SubjectSelectionListBoxValueChanged, true);
            app.SubjectSelectionListBox_2.Value = {};
            app.SelectSubjectsforInitializationLabel = uilabel(app.InitializationTab);
            app.SelectSubjectsforInitializationLabel.Text = 'Select Subjects for Initialization';
            app.Panel = uipanel(app.InitializationTab);
            app.RawDataButton_2 = uibutton(app.Panel, 'push');
            app.RawDataButton_2.ButtonPushedFcn = createCallbackFcn(app, @RawDataButtonPushed, true);
            app.RawDataButton_2.Enable = 'off';
            app.RawDataButton_2.Text = 'Raw Data ';
            app.DemographicsButton_2 = uibutton(app.Panel, 'push');
            app.DemographicsButton_2.ButtonPushedFcn = createCallbackFcn(app, @DemographicsButtonPushed, true);
            app.DemographicsButton_2.Enable = 'off';
            app.DemographicsButton_2.Text = 'Demographics';
            app.StudyOutputFolderButton_2 = uibutton(app.Panel, 'push');
            app.StudyOutputFolderButton_2.ButtonPushedFcn = createCallbackFcn(app, @StudyOutputButtonPushed, true);
            app.StudyOutputFolderButton_2.Text = 'Study Output Folder';
            app.NoneSelectedLabel_5 = uilabel(app.Panel);
            app.NoneSelectedLabel_5.Text = 'None Selected...';
            app.NoneSelectedLabel_6 = uilabel(app.Panel);
            app.NoneSelectedLabel_6.Text = 'None Selected...';
            app.NoneSelectedLabel_7 = uilabel(app.Panel);
            app.NoneSelectedLabel_7.Text = 'None Selected...';
            app.TechEnButton_2 = uibutton(app.Panel, 'state');
            app.TechEnButton_2.ValueChangedFcn = createCallbackFcn(app, @TechEnButtonValueChanged, true);
            app.TechEnButton_2.Text = 'TechEn';
            app.TechEnButton_2.Value = true;
            app.NIRxButton_2 = uibutton(app.Panel, 'state');
            app.NIRxButton_2.ValueChangedFcn = createCallbackFcn(app, @NIRxButtonValueChanged, true);
            app.NIRxButton_2.Tooltip = {''};
            app.NIRxButton_2.Text = 'NIRx';
            app.SelectFoldersLabel_2 = uilabel(app.Panel);
            app.SelectFoldersLabel_2.Text = 'Select Folders';
            app.SelectDataTypeLabel_2 = uilabel(app.Panel);
            app.SelectDataTypeLabel_2.Text = 'Select Data Type';
            app.Panel_2 = uipanel(app.InitializationTab);
            app.BuildStimsButton = uibutton(app.Panel_2, 'push');
            app.BuildStimsButton.ButtonPushedFcn = createCallbackFcn(app, @BuildStimsButtonPushed, true);
            app.BuildStimsButton.Enable = 'off';
            app.BuildStimsButton.Text = 'Build Stims';
            app.BuildStimsforSelectedSubjectsLabel = uilabel(app.Panel_2);
            app.BuildStimsforSelectedSubjectsLabel.Text = 'Build Stims for Selected Subjects';
            app.InitializedSubjectsListBoxLabel = uilabel(app.Panel_2);
            app.InitializedSubjectsListBoxLabel.Text = 'Initialized Subjects';
            app.InitializedSubjectsListBox = uilistbox(app.Panel_2);
            app.InitializedSubjectsListBox.Items = {};
            app.InitializedSubjectsListBox.ValueChangedFcn = createCallbackFcn(app, @InitializedSubjectsListBoxValueChanged, true);
            app.InitializedSubjectsListBox.Value = {};
            app.PreprocessingTab = uitab(app.TabGroup);
            app.PreprocessingTab.Title = '   Preprocessing';
            app.CheckDataQualityPanel = uipanel(app.PreprocessingTab);
            app.CheckDataQualityPanel.Title = 'Check Data Quality';
            app.Label_7 = uilabel(app.CheckDataQualityPanel);
            app.Label_7.Text = '';
            app.CriteriatoPruneChannelsLabel = uilabel(app.CheckDataQualityPanel);
            app.CriteriatoPruneChannelsLabel.Text = 'Criteria to Prune Channels';
            app.NoiseLabel = uilabel(app.CheckDataQualityPanel);
            app.NoiseLabel.Text = 'Noise (%)';
            app.SNRmax = uieditfield(app.CheckDataQualityPanel, 'numeric');
            app.SNRmax.Limits = [0 Inf];
            app.SNRmax.ValueChangedFcn = createCallbackFcn(app, @SNRmaxValueChanged, true);
            app.SNRmax.Value = 7.5;
            app.dBminLabel = uilabel(app.CheckDataQualityPanel);
            app.dBminLabel.Text = 'dB min';
            app.DBminBox = uieditfield(app.CheckDataQualityPanel, 'numeric');
            app.DBminBox.Limits = [0 Inf];
            app.DBminBox.ValueChangedFcn = createCallbackFcn(app, @DBminBoxValueChanged, true);
            app.DBminBox.Value = 110;
            app.dBmaxLabel = uilabel(app.CheckDataQualityPanel);
            app.dBmaxLabel.Text = 'dB max';
            app.DBmaxBox = uieditfield(app.CheckDataQualityPanel, 'numeric');
            app.DBmaxBox.Limits = [0 Inf];
            app.DBmaxBox.ValueChangedFcn = createCallbackFcn(app, @DBmaxBoxValueChanged, true);
            app.DBmaxBox.Value = 148;
            app.SDDistancemmLabel = uilabel(app.CheckDataQualityPanel);
            app.SDDistancemmLabel.Text = 'S-D Distance (mm)';
            app.SDminBox = uieditfield(app.CheckDataQualityPanel, 'numeric');
            app.SDminBox.Limits = [0 Inf];
            app.SDminBox.ValueChangedFcn = createCallbackFcn(app, @SDminBoxValueChanged, true);
            app.SDminBox.Value = 25;
            app.minLabel = uilabel(app.CheckDataQualityPanel);
            app.minLabel.Text = 'min';
            app.SDmaxBox = uieditfield(app.CheckDataQualityPanel, 'numeric');
            app.SDmaxBox.Limits = [0 Inf];
            app.SDmaxBox.ValueChangedFcn = createCallbackFcn(app, @SDmaxBoxValueChanged, true);
            app.SDmaxBox.Value = 35;
            app.maxLabel = uilabel(app.CheckDataQualityPanel);
            app.maxLabel.Text = 'max';
            app.dBrangeBox = uicheckbox(app.CheckDataQualityPanel);
            app.dBrangeBox.ValueChangedFcn = createCallbackFcn(app, @dBrangeBoxValueChanged, true);
            app.dBrangeBox.Text = '';
            app.dBrangeBox.Value = true;
            app.AmplitudedBLabel = uilabel(app.CheckDataQualityPanel);
            app.AmplitudedBLabel.Text = 'Amplitude (dB)';
            app.sdRangebox = uicheckbox(app.CheckDataQualityPanel);
            app.sdRangebox.ValueChangedFcn = createCallbackFcn(app, @sdRangeboxValueChanged, true);
            app.sdRangebox.Text = '';
            app.sdRangebox.Value = true;
            app.SNRbox = uicheckbox(app.CheckDataQualityPanel);
            app.SNRbox.ValueChangedFcn = createCallbackFcn(app, @SNRboxValueChanged, true);
            app.SNRbox.Text = '';
            app.SNRbox.Value = true;
            app.maxLabel_2 = uilabel(app.CheckDataQualityPanel);
            app.maxLabel_2.Text = 'max';
            app.hbMatchLabel = uilabel(app.CheckDataQualityPanel);
            app.hbMatchLabel.Text = 'hb Match';
            app.hbMatchBox = uicheckbox(app.CheckDataQualityPanel);
            app.hbMatchBox.ValueChangedFcn = createCallbackFcn(app, @hbMatchBoxValueChanged, true);
            app.hbMatchBox.Text = '';
            app.hbMatchBox.Value = true;
            app.MotionArtifactCorrectionPanel = uipanel(app.PreprocessingTab);
            app.MotionArtifactCorrectionPanel.Title = 'Motion Artifact Correction';
            app.SplineInterpolationautomatedLabel = uilabel(app.MotionArtifactCorrectionPanel);
            app.SplineInterpolationautomatedLabel.Text = 'Spline Interpolation (automated)';
            app.Spline_checkbox_2 = uicheckbox(app.MotionArtifactCorrectionPanel);
            app.Spline_checkbox_2.ValueChangedFcn = createCallbackFcn(app, @Spline_checkboxValueChanged, true);
            app.Spline_checkbox_2.Text = '';
            app.Spline_checkbox_2.Value = true;
            app.SplinewSovitzkyGolayCHECKBOX_9 = uicheckbox(app.MotionArtifactCorrectionPanel);
            app.SplinewSovitzkyGolayCHECKBOX_9.ValueChangedFcn = createCallbackFcn(app, @SplinewSovitzkyGolayCHECKBOX_9ValueChanged, true);
            app.SplinewSovitzkyGolayCHECKBOX_9.Text = '';
            app.SplinewSovitzkyGolayCHECKBOX_9.Value = true;
            app.SmoothingLabel = uilabel(app.MotionArtifactCorrectionPanel);
            app.SmoothingLabel.Text = 'Smoothing';
            app.SDwindowsLabel = uilabel(app.MotionArtifactCorrectionPanel);
            app.SDwindowsLabel.Text = 'SD window (s)';
            app.SDwindowField = uieditfield(app.MotionArtifactCorrectionPanel, 'numeric');
            app.SDwindowField.Limits = [0 Inf];
            app.SDwindowField.ValueChangedFcn = createCallbackFcn(app, @SDwindowFieldValueChanged, true);
            app.SDwindowField.Value = 8;
            app.Panel_3 = uipanel(app.PreprocessingTab);
            app.SubjectSelectionListBox_3 = uilistbox(app.Panel_3);
            app.SubjectSelectionListBox_3.Items = {};
            app.SubjectSelectionListBox_3.Multiselect = 'on';
            app.SubjectSelectionListBox_3.ValueChangedFcn = createCallbackFcn(app, @SubjectSelectionListBoxValueChanged, true);
            app.SubjectSelectionListBox_3.Value = {};
            app.RunPreprocessingButton = uibutton(app.Panel_3, 'state');
            app.RunPreprocessingButton.ValueChangedFcn = createCallbackFcn(app, @RunPreprocessingButtonPushed, true);
            app.RunPreprocessingButton.Text = 'Run Preprocessing';
            app.SelectAllSubjectsCheckBox_3 = uicheckbox(app.Panel_3);
            app.SelectAllSubjectsCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @SelectAllSubjectsCheckBox_3ValueChanged, true);
            app.SelectAllSubjectsCheckBox_3.Text = ' Select All Subjects';
            app.DataVisualizationPanel = uipanel(app.PreprocessingTab);
            app.DataVisualizationPanel.Title = 'Data Visualization';
            app.CardiacFig = uicheckbox(app.DataVisualizationPanel);
            app.CardiacFig.Text = '';
            app.SigQualityFig = uicheckbox(app.DataVisualizationPanel);
            app.SigQualityFig.Text = '';
            app.CorrectedFig = uicheckbox(app.DataVisualizationPanel);
            app.CorrectedFig.Text = '';
            app.CardiacFrequencyLabel = uilabel(app.DataVisualizationPanel);
            app.CardiacFrequencyLabel.Text = 'Cardiac Frequency';
            app.SignalQualityLabel = uilabel(app.DataVisualizationPanel);
            app.SignalQualityLabel.Text = 'Signal Quality';
            app.CorrectedSignalLabel = uilabel(app.DataVisualizationPanel);
            app.CorrectedSignalLabel.Text = 'Corrected Signal';
            app.DataQualitySummary = uicheckbox(app.DataVisualizationPanel);
            app.DataQualitySummary.Text = '';
            app.DataQualitySummaryLabel = uilabel(app.DataVisualizationPanel);
            app.DataQualitySummaryLabel.Text = 'Data Quality Summary';
            app.DataProcessingPanel = uipanel(app.PreprocessingTab);
            app.DataProcessingPanel.Title = 'Data Processing';
            app.DetrendBox = uicheckbox(app.DataProcessingPanel);
            app.DetrendBox.Text = '';
            app.DetrendBox.Value = true;
            app.DetrendLabel = uilabel(app.DataProcessingPanel);
            app.DetrendLabel.Text = 'Detrend';
            app.Downsample = uicheckbox(app.DataProcessingPanel);
            app.Downsample.ValueChangedFcn = createCallbackFcn(app, @DownsampleValueChanged, true);
            app.Downsample.Text = '';
            app.Downsample.Value = true;
            app.NormalizeLabel = uilabel(app.DataProcessingPanel);
            app.NormalizeLabel.Text = 'Normalize';
            app.NormalizeBox = uicheckbox(app.DataProcessingPanel);
            app.NormalizeBox.Text = '';
            app.NormalizeBox.Value = true;
            app.DownsampleLabel = uilabel(app.DataProcessingPanel);
            app.DownsampleLabel.Text = 'Downsample';
            app.TargetsamplerateHzEditFieldLabel = uilabel(app.DataProcessingPanel);
            app.TargetsamplerateHzEditFieldLabel.Text = ' Target sample rate (Hz)';
            app.TargetsamplerateHzEditField = uieditfield(app.DataProcessingPanel, 'numeric');
            app.TargetsamplerateHzEditField.Limits = [0 Inf];
            app.TargetsamplerateHzEditField.ValueChangedFcn = createCallbackFcn(app, @TargetsamplerateHzEditFieldValueChanged, true);
            app.TargetsamplerateHzEditField.Value = 5;
            app.PreprocessingConfigurationLabel = uilabel(app.PreprocessingTab);
            app.PreprocessingConfigurationLabel.Text = 'Preprocessing Configuration';
            app.RunPreprocessingLabel = uilabel(app.PreprocessingTab);
            app.RunPreprocessingLabel.Text = 'Run Preprocessing';
            app.PostprocessingTab = uitab(app.TabGroup);
            app.PostprocessingTab.Title = '   Postprocessing';
            app.FZBandofinterestPanel = uipanel(app.PostprocessingTab);
            app.FZBandofinterestPanel.Title = 'FZ Band of interest';
            app.LowerBoundLabel = uilabel(app.FZBandofinterestPanel);
            app.LowerBoundLabel.Text = 'Lower Bound';
            app.UpperBoundLabel = uilabel(app.FZBandofinterestPanel);
            app.UpperBoundLabel.Text = 'Upper Bound';
            app.LB = uieditfield(app.FZBandofinterestPanel, 'numeric');
            app.LB.Limits = [0 Inf];
            app.LB.ValueChangedFcn = createCallbackFcn(app, @LBValueChanged, true);
            app.LB.Value = 0.001;
            app.UB = uieditfield(app.FZBandofinterestPanel, 'numeric');
            app.UB.Limits = [0 Inf];
            app.UB.ValueChangedFcn = createCallbackFcn(app, @UBValueChanged, true);
            app.UB.Value = 0.15;
            app.ChannelSelectionPanel = uipanel(app.PostprocessingTab);
            app.ChannelSelectionPanel.Title = 'Channel Selection';
            app.LaunchSDGuiButton = uibutton(app.ChannelSelectionPanel, 'state');
            app.LaunchSDGuiButton.ValueChangedFcn = createCallbackFcn(app, @LaunchSDGuiButtonValueChanged, true);
            app.LaunchSDGuiButton.Text = 'Launch SD Gui';
            app.Region1ListBox = uilistbox(app.ChannelSelectionPanel);
            app.Region1ListBox.Items = {};
            app.Region1ListBox.Value = {};
            app.Region2ListBox = uilistbox(app.ChannelSelectionPanel);
            app.Region2ListBox.Items = {};
            app.Region2ListBox.Value = {};
            app.EditField = uieditfield(app.ChannelSelectionPanel, 'text');
            app.EditField.Value = 'Region 2';
            app.EditField_2 = uieditfield(app.ChannelSelectionPanel, 'text');
            app.EditField_2.Value = 'Region 1';
            app.HemoglobinParametersPanel_2 = uipanel(app.PostprocessingTab);
            app.HemoglobinParametersPanel_2.Title = 'Hemoglobin Parameters';
            app.CheckallhemosignalsegHbOHbTHbLabel_2 = uilabel(app.HemoglobinParametersPanel_2);
            app.CheckallhemosignalsegHbOHbTHbLabel_2.Text = 'Check all hemo signals eg. HbO,Hb,THb';
            app.HbObox_2 = uicheckbox(app.HemoglobinParametersPanel_2);
            app.HbObox_2.Text = '';
            app.HbObox_2.Value = true;
            app.Hbbox_2 = uicheckbox(app.HemoglobinParametersPanel_2);
            app.Hbbox_2.Text = '';
            app.OxyhemoglobinHbOLabel_2 = uilabel(app.HemoglobinParametersPanel_2);
            app.OxyhemoglobinHbOLabel_2.Text = 'Oxyhemoglobin (HbO)';
            app.DeoxyhemoglobinHbLabel_2 = uilabel(app.HemoglobinParametersPanel_2);
            app.DeoxyhemoglobinHbLabel_2.Text = 'Deoxyhemoglobin (Hb)';
            app.TotalhemoglobinTHbLabel_2 = uilabel(app.HemoglobinParametersPanel_2);
            app.TotalhemoglobinTHbLabel_2.Text = 'Total hemoglobin (THb)';
            app.OxygenSaturationStO2Label_2 = uilabel(app.HemoglobinParametersPanel_2);
            app.OxygenSaturationStO2Label_2.Text = 'Oxygen Saturation (StO2)';
            app.THbbox_2 = uicheckbox(app.HemoglobinParametersPanel_2);
            app.THbbox_2.Text = '';
            app.StO2box_2 = uicheckbox(app.HemoglobinParametersPanel_2);
            app.StO2box_2.Text = '';
            app.Panel_4 = uipanel(app.PostprocessingTab);
            app.SubjectSelectionListBox_4 = uilistbox(app.Panel_4);
            app.SubjectSelectionListBox_4.Items = {};
            app.SubjectSelectionListBox_4.Multiselect = 'on';
            app.SubjectSelectionListBox_4.ValueChangedFcn = createCallbackFcn(app, @SubjectSelectionListBoxValueChanged, true);
            app.SubjectSelectionListBox_4.Value = {};
            app.RunPostprocessingButton = uibutton(app.Panel_4, 'state');
            app.RunPostprocessingButton.ValueChangedFcn = createCallbackFcn(app, @RunPostprocessingButtonValueChanged, true);
            app.RunPostprocessingButton.Text = 'Run Postprocessing';
            app.SelectAllSubjectsCheckBox_4 = uicheckbox(app.Panel_4);
            app.SelectAllSubjectsCheckBox_4.ValueChangedFcn = createCallbackFcn(app, @SelectAllSubjectsCheckBox_4ValueChanged, true);
            app.SelectAllSubjectsCheckBox_4.Text = ' Select All Subjects';
            app.Label_8 = uilabel(app.Panel_4);
            app.Label_8.Text = {'Subjects who have been Initialized and Preprocessed will appear here.'; ''};
            app.PostprocessingConfigurationLabel = uilabel(app.PostprocessingTab);
            app.PostprocessingConfigurationLabel.Text = 'Postprocessing Configuration';
            app.RunPostprocessingLabel = uilabel(app.PostprocessingTab);
            app.RunPostprocessingLabel.Text = 'Run Postprocessing';
            app.FigureSelectionPanel_3 = uipanel(app.PostprocessingTab);
            app.FigureSelectionPanel_3.Title = 'Figure Selection';
            app.CheckBox = uicheckbox(app.FigureSelectionPanel_3);
            app.CheckBox.Text = '';
            app.ChannelWiseStaticCoherencePlotLabel = uilabel(app.FigureSelectionPanel_3);
            app.ChannelWiseStaticCoherencePlotLabel.Text = 'Channel Wise Static Coherence Plot';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.FigureSelectionPanel_2 = uipanel(app.AnalysisTab);
            app.FigureSelectionPanel_2.Title = 'Figure Selection';
            app.TimeVaryingWaveletTransformPowerLabel = uilabel(app.FigureSelectionPanel_2);
            app.TimeVaryingWaveletTransformPowerLabel.Text = 'Time Varying Wavelet Transform (Power)';
            app.TimevaryingCoherenceLabel = uilabel(app.FigureSelectionPanel_2);
            app.TimevaryingCoherenceLabel.Text = 'Time varying Coherence';
            app.FrequencyVaryingCoherenceByTaskLabel = uilabel(app.FigureSelectionPanel_2);
            app.FrequencyVaryingCoherenceByTaskLabel.Text = 'Frequency Varying Coherence (By Task)';
            app.tvwaveletfigure = uicheckbox(app.FigureSelectionPanel_2);
            app.tvwaveletfigure.Text = '';
            app.tvcohfigure = uicheckbox(app.FigureSelectionPanel_2);
            app.tvcohfigure.Text = '';
            app.fvcohfigure = uicheckbox(app.FigureSelectionPanel_2);
            app.fvcohfigure.Text = '';
            app.TimeVaryingPhaseLabel = uilabel(app.FigureSelectionPanel_2);
            app.TimeVaryingPhaseLabel.Text = 'Time Varying Phase';
            app.tvphasefigure = uicheckbox(app.FigureSelectionPanel_2);
            app.tvphasefigure.Text = '';
            app.AnalysisConfigurationLabel = uilabel(app.AnalysisTab);
            app.AnalysisConfigurationLabel.Text = 'Analysis Configuration';
            app.Panel_5 = uipanel(app.AnalysisTab);
            app.RunAnalysisButton_2 = uibutton(app.Panel_5, 'state');
            app.RunAnalysisButton_2.ValueChangedFcn = createCallbackFcn(app, @RunAnalysisButtonValueChanged, true);
            app.RunAnalysisButton_2.Text = 'Run Analysis';
            app.SubjectSelectionListBox_5 = uilistbox(app.Panel_5);
            app.SubjectSelectionListBox_5.Items = {};
            app.SubjectSelectionListBox_5.Multiselect = 'on';
            app.SubjectSelectionListBox_5.ValueChangedFcn = createCallbackFcn(app, @SubjectSelectionListBoxValueChanged, true);
            app.SubjectSelectionListBox_5.Value = {};
            app.SelectAllSubjectsCheckBox_5 = uicheckbox(app.Panel_5);
            app.SelectAllSubjectsCheckBox_5.ValueChangedFcn = createCallbackFcn(app, @SelectAllSubjectsCheckBox_5ValueChanged, true);
            app.SelectAllSubjectsCheckBox_5.Text = ' Select All Subjects';
            app.SubjectswhohavebeenpostproccessedwillappearhereLabel = uilabel(app.Panel_5);
            app.SubjectswhohavebeenpostproccessedwillappearhereLabel.Text = 'Subjects who have been postproccessed will appear here.';
            app.RunAnalysisLabel = uilabel(app.AnalysisTab);
            app.RunAnalysisLabel.Text = 'Run Analysis';
            app.RunAllTab = uitab(app.TabGroup);
            app.RunAllTab.Title = '   Run All';
            app.RunAllLabel = uilabel(app.RunAllTab);
            app.RunAllLabel.Text = 'Run All';
            app.SubjectSelectionListBox = uilistbox(app.RunAllTab);
            app.SubjectSelectionListBox.Items = {};
            app.SubjectSelectionListBox.Multiselect = 'on';
            app.SubjectSelectionListBox.ValueChangedFcn = createCallbackFcn(app, @SubjectSelectionListBoxValueChanged, true);
            app.SubjectSelectionListBox.Value = {};
            app.SelectAllSubjectsCheckBox_6 = uicheckbox(app.RunAllTab);
            app.SelectAllSubjectsCheckBox_6.ValueChangedFcn = createCallbackFcn(app, @SelectAllSub_RunAll, true);
            app.SelectAllSubjectsCheckBox_6.Text = ' Select All Subjects';
            app.RunAllButton = uibutton(app.RunAllTab, 'push');
            app.RunAllButton.ButtonPushedFcn = createCallbackFcn(app, @RunAllButtonPushed, true);
            app.RunAllButton.Text = 'Run All';
            app.SubjectswhohavebeeninitializedwillappearhereLabel = uilabel(app.RunAllTab);
            app.SubjectswhohavebeeninitializedwillappearhereLabel.Text = 'Subjects who have been initialized will appear here.';
            % Show the figure after all components are created
            app.EasyNIRSUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fNIRSPipeLine

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EasyNIRSUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EasyNIRSUIFigure)
        end
    end
end